classdef AOGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadCondifurationsMenu          matlab.ui.container.Menu
        SaveConfigurationsMenu          matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        DebugMenu                       matlab.ui.container.Menu
        DeactivatePeripheralsMenu       matlab.ui.container.Menu
        ExtraFiguresMenu                matlab.ui.container.Menu
        PMTSaturationInspector          matlab.ui.container.Menu
        ScanAssistantMenu               matlab.ui.container.Menu
        FindMyinstrumentPortMenu        matlab.ui.container.Menu
        InstrumentsMenu                 matlab.ui.container.Menu
        StagesMenu                      matlab.ui.container.Menu
        ScanningAssistantMenu           matlab.ui.container.Menu
        AFGMenu                         matlab.ui.container.Menu
        OscilloscopeMenu                matlab.ui.container.Menu
        ResetInstrumentsCommunicationMenu  matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        AcoustoOpticSystemLabel         matlab.ui.control.Label
        ScanPlotTab                     matlab.ui.container.TabGroup
        MainAxisTab                     matlab.ui.container.Tab
        axMainAxis                      matlab.ui.control.UIAxes
        MainPlainTab                    matlab.ui.container.Tab
        axMainPlain                     matlab.ui.control.UIAxes
        NavigatorTab                    matlab.ui.container.Tab
        axNavPlain                      matlab.ui.control.UIAxes
        MainAxisAveragedTab             matlab.ui.container.Tab
        axMainAxisRep                   matlab.ui.control.UIAxes
        MainPlainAveragedTab            matlab.ui.container.Tab
        axMainPlainRep                  matlab.ui.control.UIAxes
        NavigatorAveragedTab            matlab.ui.container.Tab
        axNavRep                        matlab.ui.control.UIAxes
        SimulationNamePanel             matlab.ui.container.Panel
        MeasurementNameEditFieldLabel   matlab.ui.control.Label
        MeasurementNameEditField        matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        DirectoryEditField              matlab.ui.control.EditField
        SaveResultsandVarsCheckBox      matlab.ui.control.CheckBox
        SaveFigsCheckBox                matlab.ui.control.CheckBox
        RawDataCheckBox                 matlab.ui.control.CheckBox
        NetSignalCheckBox               matlab.ui.control.CheckBox
        DeMultiplexedCheckBox           matlab.ui.control.CheckBox
        ReshapedCheckBox_2              matlab.ui.control.CheckBox
        FFTCheckBox_2                   matlab.ui.control.CheckBox
        SaveSpecificDataLabel           matlab.ui.control.Label
        PhiChCmplxCheckBox              matlab.ui.control.CheckBox
        Button                          matlab.ui.control.Button
        MeasMethod                      matlab.ui.container.TabGroup
        SingleMeasurementTab            matlab.ui.container.Tab
        RunButtonAO                     matlab.ui.control.Button
        RunLiveButton                   matlab.ui.control.Button
        StagesPanel_3                   matlab.ui.container.Panel
        mmLabel_17                      matlab.ui.control.Label
        mmLabel_18                      matlab.ui.control.Label
        XpositionEditField_2Label       matlab.ui.control.Label
        XpositionEditField_2            matlab.ui.control.NumericEditField
        YpositionEditField_2Label       matlab.ui.control.Label
        YpositionEditField_2            matlab.ui.control.NumericEditField
        MeasureatcurrentposCheckBox     matlab.ui.control.CheckBox
        LiveAcoustoOpticsPanel          matlab.ui.container.Panel
        LimitLiveAOCheckBox             matlab.ui.control.CheckBox
        NEditFieldLabel                 matlab.ui.control.Label
        NEditField                      matlab.ui.control.NumericEditField
        ImportsavedResultsPanel         matlab.ui.container.Panel
        DataEditFieldLabel              matlab.ui.control.Label
        DataEditField                   matlab.ui.control.EditField
        analysisDataPathButton          matlab.ui.control.Button
        AnalyzeOrigButton               matlab.ui.control.Button
        VarsEditFieldLabel              matlab.ui.control.Label
        VarsEditField                   matlab.ui.control.EditField
        analysisVarsPathButton          matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        AnalyizeNewButton               matlab.ui.control.Button
        ImportVarsButton                matlab.ui.control.Button
        ExportVarsButton                matlab.ui.control.Button
        ConsistencyTab                  matlab.ui.container.Tab
        RunButtonConsistency            matlab.ui.control.Button
        StagesPanel_2                   matlab.ui.container.Panel
        mmLabel_10                      matlab.ui.control.Label
        mmLabel_11                      matlab.ui.control.Label
        XpositionEditFieldLabel         matlab.ui.control.Label
        XpositionEditField              matlab.ui.control.NumericEditField
        YpositionEditFieldLabel         matlab.ui.control.Label
        YpositionEditField              matlab.ui.control.NumericEditField
        TimeFramePropertiesPanel        matlab.ui.container.Panel
        sLabel                          matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        MaxTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MaxTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        MinTimeFrameDurationEditFieldLabel  matlab.ui.control.Label
        MinTimeFrameDurationEditField   matlab.ui.control.NumericEditField
        TimeStrideEditFieldLabel        matlab.ui.control.Label
        TimeStrideEditField             matlab.ui.control.NumericEditField
        SetsperTimeFrameEditFieldLabel  matlab.ui.control.Label
        SetsperTimeFrameEditField       matlab.ui.control.NumericEditField
        ImportResultsButton_2           matlab.ui.control.Button
        ExportScanVarsButton_2          matlab.ui.control.Button
        ImportScanVarsButton_2          matlab.ui.control.Button
        FiguresPanel_2                  matlab.ui.container.Panel
        FrameToPlotSpinnerLabel         matlab.ui.control.Label
        FrameToPlotSpinner              matlab.ui.control.Spinner
        SettoplotSpinnerLabel           matlab.ui.control.Label
        SettoplotSpinner                matlab.ui.control.Spinner
        ZCoorSpinnerLabel               matlab.ui.control.Label
        ZCoorConsSpinner                matlab.ui.control.Spinner
        sLabel_6                        matlab.ui.control.Label
        mmLabel_15                      matlab.ui.control.Label
        Scan2DTab                       matlab.ui.container.Tab
        StagesPanel                     matlab.ui.container.Panel
        StagesCalibToolButton           matlab.ui.control.Button
        mmLabel_4                       matlab.ui.control.Label
        mmLabel_5                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_7                       matlab.ui.control.Label
        mmLabel_8                       matlab.ui.control.Label
        mmLabel_9                       matlab.ui.control.Label
        XstartpointEditField_2Label     matlab.ui.control.Label
        XstartpointEditField            matlab.ui.control.NumericEditField
        XendpointEditField_2Label       matlab.ui.control.Label
        XendpointEditField              matlab.ui.control.NumericEditField
        YstartpointEditField_2Label     matlab.ui.control.Label
        YstartpointEditField            matlab.ui.control.NumericEditField
        YendPointEditField_2Label       matlab.ui.control.Label
        YendPointEditField              matlab.ui.control.NumericEditField
        XstrideEditFieldLabel           matlab.ui.control.Label
        XstrideEditField                matlab.ui.control.NumericEditField
        YstrideEditFieldLabel           matlab.ui.control.Label
        YstrideEditField                matlab.ui.control.NumericEditField
        MainAxisScanDropDownLabel       matlab.ui.control.Label
        MainAxisScanDropDown            matlab.ui.control.DropDown
        ImportLimitsfromfileButton      matlab.ui.control.Button
        RunButtonScan2D                 matlab.ui.control.Button
        RepeatsPanel                    matlab.ui.container.Panel
        RepeatsEditFieldLabel           matlab.ui.control.Label
        RepeatsEditField                matlab.ui.control.NumericEditField
        ImportResultsButton             matlab.ui.control.Button
        ExportScanVarsButton            matlab.ui.control.Button
        ImportScanVarsButton            matlab.ui.control.Button
        FiguresPanel                    matlab.ui.container.Panel
        PlainDropDownLabel              matlab.ui.control.Label
        PlainDropDown                   matlab.ui.control.DropDown
        ReapeatSpinnerLabel             matlab.ui.control.Label
        ReapeatSpinner                  matlab.ui.control.Spinner
        ZCoorMainAxisLabel              matlab.ui.control.Label
        ZCoorS2dSpinner                 matlab.ui.control.Spinner
        PlainIdxSpinnerLabel            matlab.ui.control.Label
        PlainIdxSpinner                 matlab.ui.control.Spinner
        mmLabel_14                      matlab.ui.control.Label
        mmLabel_19                      matlab.ui.control.Label
        ConsistencyPlotTab              matlab.ui.container.TabGroup
        AllFramesStatisticsTab          matlab.ui.container.Tab
        axAllFrames                     matlab.ui.control.UIAxes
        CurrentFrameTab                 matlab.ui.container.Tab
        axCurFrame                      matlab.ui.control.UIAxes
        CurrentSetQuantsTab             matlab.ui.container.Tab
        axCurSet                        matlab.ui.control.UIAxes
        FramesNavigatorTab              matlab.ui.container.Tab
        axNavFrame                      matlab.ui.control.UIAxes
        SetsNavigatorQuantsTab          matlab.ui.container.Tab
        axNavSet                        matlab.ui.control.UIAxes
        GlobalParameters                matlab.ui.container.TabGroup
        SignalExcitationTab             matlab.ui.container.Tab
        UseQuantCheckBox                matlab.ui.control.CheckBox
        SamplingFrequencyextClkPanel    matlab.ui.container.Panel
        MHzLabel_2                      matlab.ui.control.Label
        VLabel_10                       matlab.ui.control.Label
        VLabel_11                       matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        FrequencyEditFieldLabel         matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        AmplitudeEditField_4Label       matlab.ui.control.Label
        AmplitudeEditField_4            matlab.ui.control.EditField
        OffsetEditFieldLabel            matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.EditField
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        DutyCycleEditField              matlab.ui.control.NumericEditField
        UltrasoundSignalPropertiesPanel  matlab.ui.container.Panel
        MHzLabel                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        KHzLabel_3                      matlab.ui.control.Label
        SineFrequencyEditFieldLabel     matlab.ui.control.Label
        SineFrequencyEditField          matlab.ui.control.NumericEditField
        TrainFrequencyEditFieldLabel    matlab.ui.control.Label
        TrainFrequencyEditField         matlab.ui.control.NumericEditField
        SinePeriodsEditFieldLabel       matlab.ui.control.Label
        SinePeriodsEditField            matlab.ui.control.NumericEditField
        OptimizetrainfrequencyCheckBox  matlab.ui.control.CheckBox
        GeometryPanel                   matlab.ui.container.Panel
        PhantomdepthEditFieldLabel      matlab.ui.control.Label
        PhantomdepthEditField           matlab.ui.control.NumericEditField
        TransducerheightEditFieldLabel  matlab.ui.control.Label
        TransducerheightEditField       matlab.ui.control.NumericEditField
        TransducerdistanceLabel         matlab.ui.control.Label
        TransducerdistanceEditField     matlab.ui.control.NumericEditField
        msLabel                         matlab.ui.control.Label
        SpeedinMediumEditFieldLabel     matlab.ui.control.Label
        SpeedinMediumEditField          matlab.ui.control.NumericEditField
        cmLabel_3                       matlab.ui.control.Label
        cmLabel_2                       matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        TimetosampleEditFieldLabel      matlab.ui.control.Label
        TimetosampleEditField           matlab.ui.control.NumericEditField
        UseTimeQuantCheckBox            matlab.ui.control.CheckBox
        QuantTimeEditFieldLabel         matlab.ui.control.Label
        QuantTimeEditField              matlab.ui.control.NumericEditField
        FastAnalysisCheckBox            matlab.ui.control.CheckBox
        UseGPUCheckBox                  matlab.ui.control.CheckBox
        CalcOnlyUSFrequencyLabel        matlab.ui.control.Label
        secLabel                        matlab.ui.control.Label
        secLabel_2                      matlab.ui.control.Label
        UseHadamardCodeCheckBox         matlab.ui.control.CheckBox
        PeripheralsTab                  matlab.ui.container.Tab
        FunctionGeneratorPanel          matlab.ui.container.Panel
        SclkEditFieldLabel              matlab.ui.control.Label
        SclkEditField                   matlab.ui.control.NumericEditField
        DAQFilterDropDownLabel          matlab.ui.control.Label
        DAQFilterDropDown               matlab.ui.control.DropDown
        TriggerWidthEditFieldLabel      matlab.ui.control.Label
        TriggerWidthEditField           matlab.ui.control.NumericEditField
        AmplitudeEditField_3Label       matlab.ui.control.Label
        AmplitudeEditField_3            matlab.ui.control.NumericEditField
        BiasEditFieldLabel              matlab.ui.control.Label
        BiasEditField                   matlab.ui.control.NumericEditField
        MSsLabel                        matlab.ui.control.Label
        SLabel                          matlab.ui.control.Label
        VLabel_8                        matlab.ui.control.Label
        VLabel_9                        matlab.ui.control.Label
        DigitizerPanel                  matlab.ui.container.Panel
        Label_10                        matlab.ui.control.Label
        NumberOfChannelsEditFieldLabel  matlab.ui.control.Label
        NumberOfChannelsEditField       matlab.ui.control.NumericEditField
        Label_11                        matlab.ui.control.Label
        PeriodtoSampleEditFieldLabel    matlab.ui.control.Label
        PeriodtoSampleEditField         matlab.ui.control.NumericEditField
        VppLabel                        matlab.ui.control.Label
        VoltsRangeEditFieldLabel        matlab.ui.control.Label
        VoltsRangeEditField             matlab.ui.control.NumericEditField
        SamplingMethodDropDownLabel     matlab.ui.control.Label
        SamplingMethodDropDown          matlab.ui.control.DropDown
        LaserPanel                      matlab.ui.container.Panel
        nmLabel                         matlab.ui.control.Label
        mWLabel                         matlab.ui.control.Label
        KHzLabel_4                      matlab.ui.control.Label
        sLabel_5                        matlab.ui.control.Label
        WavelengthEditFieldLabel        matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        LineWidthEditFieldLabel         matlab.ui.control.Label
        LineWidthEditField              matlab.ui.control.NumericEditField
        SpeckleDurationEditFieldLabel   matlab.ui.control.Label
        SpeckleDurationEditField        matlab.ui.control.NumericEditField
        TurnOffAFGButton                matlab.ui.control.Button
        CloseIOPortButton               matlab.ui.control.Button
        StagesHomeButton                matlab.ui.control.Button
        ProblemVarsTab                  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        SystemClkTab                    matlab.ui.container.Tab
        UITable_6                       matlab.ui.control.Table
        USSignalTab                     matlab.ui.container.Tab
        UITable_5                       matlab.ui.control.Table
        SamplesTab                      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        TimingTab                       matlab.ui.container.Tab
        UITable_3                       matlab.ui.control.Table
        DigitizerTab_2                  matlab.ui.container.Tab
        UITable_4                       matlab.ui.control.Table
        LengthTab                       matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        FreqTab                         matlab.ui.container.Tab
        UITable_7                       matlab.ui.control.Table
        PlottingTab                     matlab.ui.container.Tab
        AcoustoOpticsPanel              matlab.ui.container.Panel
        PlotChannelSpinnerLabel         matlab.ui.control.Label
        PlotChannelSpinner              matlab.ui.control.Spinner
        ZCoorSpinner_3Label             matlab.ui.control.Label
        ZCoorAOSpinner                  matlab.ui.control.Spinner
        QuantSpinnerLabel               matlab.ui.control.Label
        QuantAOSpinner                  matlab.ui.control.Spinner
        ExternalClkCheckBox             matlab.ui.control.CheckBox
        UltrasoundSignalCheckBox        matlab.ui.control.CheckBox
        FullSignalCheckBox              matlab.ui.control.CheckBox
        MeasuredSamplesCheckBox         matlab.ui.control.CheckBox
        NetSamplesCheckBox              matlab.ui.control.CheckBox
        ReshapedCheckBox_3              matlab.ui.control.CheckBox
        FFTCheckBox_3                   matlab.ui.control.CheckBox
        PhiChCheckBox                   matlab.ui.control.CheckBox
        PhiCheckBox                     matlab.ui.control.CheckBox
        PhiQuantCheckBox                matlab.ui.control.CheckBox
        DemultiplexedCheckBox           matlab.ui.control.CheckBox
        FreqEnviromentEditFieldLabel    matlab.ui.control.Label
        FreqEnviromentEditField         matlab.ui.control.NumericEditField
        KHzLabel_5                      matlab.ui.control.Label
        mmLabel_16                      matlab.ui.control.Label
        dispFullFFTCheckBox             matlab.ui.control.CheckBox
        QuantAvgFFTCheckBox             matlab.ui.control.CheckBox
        FiguresControlPanel             matlab.ui.container.Panel
        resetLowerAxLimits              matlab.ui.control.Button
        EndEditFieldLabel               matlab.ui.control.Label
        EndLimitLower                   matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        StartLimitLower                 matlab.ui.control.NumericEditField
        LowerAxesLabel                  matlab.ui.control.Label
        resetUpperAxLimits              matlab.ui.control.Button
        UpperAxesLabel                  matlab.ui.control.Label
        StartEditField_2Label           matlab.ui.control.Label
        StartLimitUpper                 matlab.ui.control.NumericEditField
        setUpperLimits                  matlab.ui.control.Button
        setLowerLimits                  matlab.ui.control.Button
        EndEditField_2Label             matlab.ui.control.Label
        EndLimitUpper                   matlab.ui.control.NumericEditField
        FiguresinindependantwindowCheckBox  matlab.ui.control.CheckBox
        StopButton                      matlab.ui.control.Button
        PopoutFiguresPanel              matlab.ui.container.Panel
        PopButton                       matlab.ui.control.Button
        DropDown                        matlab.ui.control.DropDown
        acoustoOpticsPlotsTab           matlab.ui.container.TabGroup
        TimeTable                       matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        UITable2_2                      matlab.ui.control.Table
        UITable2_3                      matlab.ui.control.Table
        FFTTab                          matlab.ui.container.Tab
        axFFTsm                         matlab.ui.control.UIAxes
        AverageFFTTab                   matlab.ui.container.Tab
        axQAvgFFT                       matlab.ui.control.UIAxes
        PhiTab                          matlab.ui.container.Tab
        axPhi                           matlab.ui.control.UIAxes
        PhichTab                        matlab.ui.container.Tab
        axPhiCh                         matlab.ui.control.UIAxes
        UltrasoundSignalTab             matlab.ui.container.Tab
        axUsSignal                      matlab.ui.control.UIAxes
        ReshapedSignalTab               matlab.ui.container.Tab
        axReshape                       matlab.ui.control.UIAxes
        NetSignalTab                    matlab.ui.container.Tab
        axNetSignal                     matlab.ui.control.UIAxes
        DemultiplexedTab                matlab.ui.container.Tab
        axDeMul                         matlab.ui.control.UIAxes
        MeasuredSignalTab               matlab.ui.container.Tab
        axMeasSig                       matlab.ui.control.UIAxes
        FullSignalTab                   matlab.ui.container.Tab
        axFullSignal                    matlab.ui.control.UIAxes
        ExternalClkTab                  matlab.ui.container.Tab
        axExtClk                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        %parameters
        importedLimits % Description

        %--------------------------
        % New Variables
        %--------------------------
%         uVarsAcoustoOptics;
        uVarsConsistency;
        uVarsS2d;
        
        %objects
        algo;
        ao;
        consistency;
        s2d;
%         stages;
        
        %Graphics Objects Handles
        aoGraphics;
        hConsGraph;
        hS2dGraph;
        
        % Results Data Structures
        algoValues;
        
        % Control Vars
        initConnsistency;
        initS2d;
        s2dStagesRecon
        consStagesRecon
        
        % AO Measurement Vars
%         AOVars;
        consVars
        s2dVars
        
        % Data vars
        algoData;
        aoData;
        AOData; %tmp
        consData;
        s2dData;
        
        % eventsListeners
        consListeners;
        s2dListeners;
        aoListeners;
        
        %GUI control Variables
        GUIctrl;
    end
    
    events
        updateQuant
        updateSet
        updateFrame
        
        updatePhiRepEvent
        updatePhiEvent
        
        timeTable
    end
    
    methods (Access = public)
        
        function displayAOTimeTable(app, ~, ~)
            tic;
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',sum([timeTable.acq timeTable.analyse timeTable.moveData])}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.analyse}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
    
            app.UITable2.Data = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;  
            toc
        end

%---------------- Consistency Functions ---------------------%

%         Consistency Listener Responses
%         function updateCurrentSetPlots(app, ~, ~)
%             if app.consData.quantDataValid
%                 app.hConsGraph.dispCurrentSet(1:app.consData.numOfQuant(app.consData.curIdx(1)) , app.consData.phiQuant);
%             end
%         end
    
        function updateCurrentFramePlots(app, ~, ~)
            if app.consData.setDataValid
                app.hConsGraph.dispCurrentSet(1:app.consData.numOfQuant(app.consData.curIdx(1)) , app.consData.phiQuant);
                app.hConsGraph.dispCurrentFrame(1:app.consData.numOfSets, app.consData.phiSet,  app.consData.phiSetStd);
            end
        end
        
        function updateTotalFramePlots(app, ~, ~)
            if app.consData.frameDataValid
                app.hConsGraph.dispTotalFrame( app.consData.timeFrames, app.consData.phiFrame,  app.consData.phiFrameStd);
            end
        end
        
        % Consistency Navigator Plots functions
        function updateNavFramePlots(app)
            if app.consData.frameDataValid
                idx = app.FrameToPlotSpinner.Value;
                app.hConsGraph.setNavFrame(idx)
                app.hConsGraph.dispNavFrame( 1:app.consData.numOfSets, app.consData.phiSet, app.consData.phiSetStd);
            end
        end
        
        function updateNavSetPlots(app)
            if app.consData.setDataValid
                frame = app.FrameToPlotSpinner.Value;
                idx = app.SettoplotSpinner.Value;
                app.hConsGraph.setNavSet(idx)
                app.hConsGraph.dispNavSet( 1:app.consData.numOfQuant(frame), app.consData.phiQuant);
            end
        end
    
        % Consistency Data Update functions
%         function updateConsistencyPhiQuant(app, data, curIdx)
%                 app.consData.phiQuant = data;
%                 app.consData.curIdx   = curIdx;
%                 
%                 app.consData.quantDataValid = true;
%         end
    
        function updateConsistencyPhiSet(app, dataQuant, dataSet, std, curIdx)
                app.consData.phiQuant  = dataQuant;
                app.consData.phiSet    = dataSet;
                app.consData.phiSetStd = std;
                app.consData.curIdx    = curIdx;
                
                app.updateQuantSpinner(app.consData.numOfQuant(curIdx(1)))
                app.consData.setDataValid = true;
                
        end
        
        function updateConsistencyPhiFrame(app, data, std, curIdx)
                app.consData.phiFrame    = data;
                app.consData.phiFrameStd = std;
                app.consData.curIdx      = curIdx;
                
                app.consData.frameDataValid = true;
        end
    
        function updateConsChAndPos(app)
            ch    = app.PlotChannelSpinner.Value;
            zIdx  = app.ZCoorConsSpinner.Value;
            quant = app.QuantAOSpinner.Value;
            
            % Update the graphic object
            app.consistency.graphics.gReq.ch    = ch;
            app.consistency.graphics.gReq.zIdx  = zIdx;
            app.consistency.graphics.gReq.quant = quant; 
            app.hConsGraph.setChAndPos(ch, zIdx, quant);
            
            % Update Plots
%             app.updateCurrentSetPlots();
            app.updateCurrentFramePlots();
            app.updateTotalFramePlots();
            
            app.updateNavFramePlots();
            app.updateNavSetPlots();
        end

        function initConsData(app, arrays, scan, zVec)
           names = fieldnames(scan);
           for i = 1:length(names)
                app.consData.(names{i}) = scan.(names{i});
           end
           app.consData.zVec        = zVec;
           app.consData.zVecSpinner = floor(zVec*1e5)/1e2;
           app.consData.varsValid = true;
           
           app.consData.phiQuant    =  arrays.phiQuant;
           app.consData.phiSet      =  arrays.phiSets; 
           app.consData.phiSetStd   =  arrays.phiSetsStd; 
           app.consData.phiFrame    =  arrays.phiFrame;
           app.consData.phiFrameStd =  arrays.phiFrameStd;
           
           app.consData.frameDataValid = true;
           app.consData.setDataValid   = true;
%            app.consData.quantDataValid = true;
            
           app.consData.curIdx = ones(1,3);
           
           app.AOData.zVec = zVec;
           
           app.updatePlotZSpinner();
           app.updateConsChAndPos();
           app.updateFrameAndSetSpinners();
           
           app.updateAOPlotZSpinner(zVec);
%            app.updateCurrentSetPlots();
           app.updateCurrentFramePlots();
           app.updateTotalFramePlots();
           app.updateNavFramePlots();
           app.updateNavSetPlots();
        end
    
%---------------- Scan 2d Data and Plot Functions ---------------------%    
        % Scan 2d Plots functions (Listeners)
        function updateS2DPlots(app, ~, ~)
            if app.s2dData.phiValid
                app.hS2dGraph.dispCurMainAxis(app.s2dData.phiRep, app.s2dData.phiRepStd);
                app.hS2dGraph.dispCurMainPlain(app.s2dData.phiRep);
                app.updateS2dNavigator();
            end
        end
        
        function updateS2DPlotsAVG(app, ~, ~)
            if app.s2dData.phiRepValid
                app.hS2dGraph.dispCurMainAxisRep(app.s2dData.phi, app.s2dData.phiStd);
                app.hS2dGraph.dispCurMainPlainRep(app.s2dData.phi);
                app.updateS2dNavigator();
                
            end
        end
  
        % Scan 2D Data Update functions
        function updatePhiRep(app, data, std, curIdx)
            app.s2dData.phiRep      = data;
            app.s2dData.phiRepStd   = std;
            app.s2dData.curIdx      = curIdx;
            app.s2dData.phiRepValid = true;
        end
    
        function updatePhi(app, data, std)
            app.s2dData.phi      = data;
            app.s2dData.phiStd   = std;
            app.s2dData.phiValid = true;
        end

        function updateS2dGeneralData(app, zVec, xVec, yVec, rep, numOfQuants)
            % object updates these variable backwards
            
            app.s2dData.zVec = zVec;
            app.s2dData.xVec = xVec;
            app.s2dData.yVec = yVec;
            app.s2dData.rep  = rep;
            app.s2dData.numOfQuant  = numOfQuants;
            
            app.s2dData.varsValid = true;
            
            app.AOData.zVec = zVec;
            app.AOData.numOfQuant = numOfQuants;
            
            app.updateAOPlotZSpinner(zVec);
            app.updateQuantSpinner(numOfQuants)
            app.updatePlaneIdxSelect();
            app.updateMainZSpinner();
            app.updatePlotRepSpinner();
            app.updateS2dNavigator();
        end
    end

    methods (Access = private)

        function applyImportedLimits(app)
             app.XstartpointEditField.Value = app.importedLimits.startPosX; 
             app.XendpointEditField.Value   = app.importedLimits.endPosX; 
             app.YstartpointEditField.Value = app.importedLimits.startPosY; 
             app.YendPointEditField.Value   = app.importedLimits.endPosY; 
             app.XstrideEditField.Value     = app.importedLimits.Xstride; 
             app.YstrideEditField.Value     = app.importedLimits.Ystride; 
        end
    
        function data = extractStructDataToTable(~, curStruct)
            names= fieldnames(curStruct);
            j=1;
            for i=1:numel(names)
                %this function requires mapping toolbox
                fieldVal  = extractfield(curStruct, names{i});
                if length(fieldVal)> 1
                    continue;
                end
                data{j,1} = names{i};
                data{j,2} = fieldVal;
                data{j,3} = 'unit';
                j=j+1;
            end
        end
        
        % ------------ Acousto Optics GUI Components Update Functions ------------------%
        function updateChannelSpinner(app)
            app.PlotChannelSpinner.Limits = [1, app.NumberOfChannelsEditField.Value];
        end
        
        function updateAOZLabel(app)
            app.mmLabel_16.Text = sprintf("%.2f[mm]", app.aoData.curVars.measVars.algo.len.zVecUSRes(app.ZCoorAOSpinner.Value)*1e3);
        end

        function updateAOChAndPos(app)
            ch   = app.PlotChannelSpinner.Value;
            zIdx = app.ZCoorAOSpinner.Value;
            quantIdx = app.QuantAOSpinner.Value; 
      
            app.hAOGraph.setChAndPos(ch, zIdx, quantIdx);
            app.consistency.acoustoOptics.vars.uVars.figs.ch = ch;
            app.consistency.acoustoOptics.vars.uVars.figs.zIdx = zIdx;
        end
        
        function updateAOPlotZSpinner(app, zVec)
                app.ZCoorAOSpinner.Limits = [1, length(zVec)]; 
                app.ZCoorAOSpinner.Value  = 1;
                app.ZCoorAOSpinner.Step   = 1; 
        end
    
        function updateQuantSpinner(app, numOfQuant)
            app.QuantAOSpinner.Limits = [1, numOfQuant]; 
            app.QuantAOSpinner.Value  = 1;
            app.QuantAOSpinner.Step   = 1;     
        end
        
        function updateAOFigs(app)
            if ~isempty(app.aoData.curRes)
                figsVars = app.collectAOGraphicVars();
                app.ao.setFigsVars(figsVars);
            end
        end
        % ------------ Consistency GUI Components Update Functions ------------------%
        function updateConsFrameLabel(app)
            app.sLabel_6.Text = sprintf("%.2f[mm]",app.consData.timeFrames(app.FrameToPlotSpinner.Value));
        end
        
        function updateFrameAndSetSpinners(app)
%             if app.consData.varsValid
%                 app.FrameToPlotSpinner.Limits = [1,length(app.consData.timeFrames)];
%                 app.FrameToPlotSpinner.Value  = 1;
%                 app.FrameToPlotSpinner.Step   = 1;
%                 app.FrameToPlotSpinner.Enable = true;
%                 if (app.SetsperTimeFrameEditField.Value ==1)
%                     app.SettoplotSpinner.Enable   = false;
%                 else
%                     app.SettoplotSpinner.Limits   = [1, app.SetsperTimeFrameEditField.Value];
%                     app.SettoplotSpinner.Enable   = true;
%                 end
%             else
%                 app.FrameToPlotSpinner.Enable = false;
%                 app.SettoplotSpinner.Enable   = false;    
%             end
        end

        function setPositionsFromCalib(app, stages)
            app.importedLimits = stages;
        end
        
        function updateConsZLabel(app)
            app.mmLabel_15.Text = sprintf("%.2f[mm]",app.consData.zVecSpinner(app.ZCoorConsSpinner.Value));
        end
    
        function updatePlotZSpinner(app)
%             if app.consData.varsValid
%                 app.ZCoorConsSpinner.Limits = [1,length(app.consData.zVec)];
%                 app.ZCoorConsSpinner.Step   = 1;
%                 app.ZCoorConsSpinner.Value  = 1;
%                 app.updateConsZLabel()
%                 app.ZCoorConsSpinner.Enable = true;
%             else
%                 app.ZCoorConsSpinner.Enable = false;
%                 app.ZCoorConsSpinner.Value  = 1;
%             end
        end
    
        % ------------ Scan 2D GUI Components Update Functions ------------------%
        function updateMainZSpinner(app)
%             if app.s2dData.varsValid
%                 app.ZCoorS2dSpinner.LowerLimitInclusive = 'on';
%                 app.ZCoorS2dSpinner.Limits = [1, length(app.s2dData.zVec)];
%                 app.ZCoorS2dSpinner.Step   = 1;
%                 app.ZCoorS2dSpinner.Value  = 1;
%                 app.ZCoorS2dSpinner.Enable = true; 
%             else
%                 app.ZCoorS2dSpinner.Value = 1;
%                 app.ZCoorS2dSpinner.Enable = false;
%             end
        end    
    
        function updatePlotRepSpinner(app)
            app.ReapeatSpinner.LowerLimitInclusive = 'on';
            if app.s2dData.rep > 1
               app.ReapeatSpinner.Enable = true;
               app.ReapeatSpinner.Limits = [1, app.s2dData.rep];
               app.ReapeatSpinner.Step   = 1; 
               app.ReapeatSpinner.Value  = 1;
            else
               app.ReapeatSpinner.Limits = [1,2];
               app.ReapeatSpinner.Value  = 1;
               app.ReapeatSpinner.Enable = false;
            end
        end
 
        function updatePlainCoorLabel(app)
           plain = app.PlainDropDown.Value;
           idx = app.PlainIdxSpinner.Value;
           switch plain
               case 'X'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.s2dData.xVec(idx));
               case 'Y'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.s2dData.yVec(idx));
               case 'Z'
                   app.mmLabel_14.Text = sprintf("%.2f[mm]", app.s2dData.zVec(idx)*1e3);
           end
        end
    
        function updateMainAxisCoorLabel(app)
            idx = app.ZCoorS2dSpinner.Value;
            app.mmLabel_13.Text = sprintf("%.2f[mm]", app.s2dData.zVec(idx)*1e3);
        end
    
        function updatePlaneIdxSelect(app)
           plain = app.PlainDropDown.Value;
           app.PlainIdxSpinner.LowerLimitInclusive = 'on';
           switch plain
                case 'X'
                    app.PlainIdxSpinner.Limits = [1, length(app.s2dData.xVec)];
                case 'Y'
                    app.PlainIdxSpinner.Limits = [1, length(app.s2dData.yVec)];
                case 'Z'
                    app.PlainIdxSpinner.Limits = [1, length(app.s2dData.zVec)];
           end
           app.PlainIdxSpinner.Step  = 1;
           app.PlainIdxSpinner.Value = 1;
           app.updatePlainCoorLabel();
        end
    
        function updateS2dChAndPos(app)
            ch   = app.PlotChannelSpinner.Value;
            zIdx = app.ZCoorS2dSpinner.Value;
            quant = app.QuantAOSpinner.Value;
            % Update the graphic object
            app.s2d.graphics.gReq.ch   = ch;
            app.s2d.graphics.gReq.zIdx = zIdx;
            app.s2d.graphics.gReq.quant = quant;
            
            app.hS2dGraph.setChAndPos(ch, zIdx, quant);
            
            % Update Plots
            app.updateS2DPlotsAVG();
            app.updateS2DPlots();
        end
    
        function updateS2dNavigator(app)
            plain = app.PlainDropDown.Value;
            idx   = app.PlainIdxSpinner.Value;
            rep = app.ReapeatSpinner.Value;
            app.hS2dGraph.setNavParams(plain, idx, rep);
            if app.s2dData.phiRepValid
                app.hS2dGraph.dispNavPlain(app.s2dData.phiRep);
            end
            if app.s2dData.phiValid
                app.hS2dGraph.dispNavPlainRep(app.s2dData.phi);
            end
        end
 
        %---------------- Algo Functions ---------------%
        function initAlgoVars(app)
           app.collectAlgoVars();

           app.algoData.uVars.bufferSizeBytes   = 8*2^20;
           app.algoData.uVars.bytesPerSample    = 2;
           app.algoData.uVars.preTriggerSamples = 14;

        end
        
        function collectAlgoVars(app) 
           app.algoData.uVars.c               = app.SpeedinMediumEditField.Value; 
           app.algoData.uVars.fSin            = app.SineFrequencyEditField.Value*1e6;              
           app.algoData.uVars.fTrain          = app.TrainFrequencyEditField.Value*1e3;
           app.algoData.uVars.cycInPulse      = app.SinePeriodsEditField.Value;
           app.algoData.uVars.channels        = app.NumberOfChannelsEditField.Value;
           app.algoData.uVars.c               = app.SpeedinMediumEditField.Value;  
           app.algoData.uVars.phantomDepth    = app.PhantomdepthEditField.Value*1e-2;
           app.algoData.uVars.distFromPhantom = app.TransducerdistanceEditField.Value*1e-2;
           app.algoData.uVars.fExtClk         = app.FrequencyEditField.Value*1e6; %fs
           app.algoData.uVars.fSclk           = app.SclkEditField.Value*1e6;
           app.algoData.uVars.extClkDcyc      = app.DutyCycleEditField.Value;
           app.algoData.uVars.useGPU          = false;
           app.algoData.uVars.useQuant        = app.UseTimeQuantCheckBox.Value;
           app.algoData.uVars.quantTime       = app.QuantTimeEditField.Value;
           app.algoData.uVars.useHadamard     = app.UseHadamardCodeCheckBox.Value;
           app.algoData.uVars.timeToSample    = app.TimetosampleEditField.Value;
       end
            
        function setAlgoValuesTable(app)
            data = app.extractStructDataToTable(app.algoValues.extClk);
            app.UITable_6.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.usSignal);
            app.UITable_5.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.samples);
            app.UITable.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.timing);
            app.UITable_3.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.digitizer);
            app.UITable_4.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.len);
            app.UITable_2.Data = data;
            
            data = app.extractStructDataToTable(app.algoValues.freq);
            app.UITable_7.Data = data;
        end
        

        %---------------- Acousto Optics Functions ---------------%       
        function uVars = collectAOVars(app)
            app.aoData.uVars.c                 = app.SpeedinMediumEditField.Value;
            app.aoData.uVars.fSin              = app.SineFrequencyEditField.Value*1e6;              
            app.aoData.uVars.fTrain            = app.TrainFrequencyEditField.Value*1e3;
            app.aoData.uVars.cycInPulse        = app.SinePeriodsEditField.Value; 
            
            app.aoData.uVars.phantomDepth      = app.PhantomdepthEditField.Value*1e-2;
            app.aoData.uVars.distFromPhantom   = app.TransducerdistanceEditField.Value*1e-2;
            app.aoData.uVars.fExtClk           = app.FrequencyEditField.Value*1e6;
            app.aoData.uVars.timeToSample      = app.TimetosampleEditField.Value;
            app.aoData.uVars.extClkDcyc        = app.DutyCycleEditField.Value;
            app.aoData.uVars.fastAnalysis      = app.FastAnalysisCheckBox.Value;
             

            app.aoData.uVars.useQuant          = app.UseTimeQuantCheckBox.Value;
            app.aoData.uVars.quantTime         = app.QuantTimeEditField.Value;
            app.aoData.uVars.useHadamard       = app.UseHadamardCodeCheckBox.Value;
            
            app.aoData.uVars.fSclk             = app.SclkEditField.Value*1e6;
            
            app.aoData.uVars.channels          = app.NumberOfChannelsEditField.Value;
            
            app.aoData.uVars.useGPU            = app.UseGPUCheckBox.Value;
            
            % Stages control Vars
            if app.MeasureatcurrentposCheckBox.Value
                app.aoData.uVars.stages.xPos       = [];
                app.aoData.uVars.stages.yPos       = [];
            else
                app.aoData.uVars.stages.xPos       = app.XpositionEditField_2.Value;
                app.aoData.uVars.stages.yPos       = app.YpositionEditField_2.Value;
            end
            
            app.collectAOGraphicVars();
            
            % set file system properties
            app.collectAOFileSystemVars();
            
            % Set Acousto Optics Object Control Vars
            app.aoData.uVars.useLoadedRawData = false;
            app.aoData.uVars.loadDataFilename = app.DataEditField.Value;
            app.aoData.uVars.measHere         = app.MeasureatcurrentposCheckBox.Value;
            app.aoData.uVars.limitByN         = app.LimitLiveAOCheckBox.Value;
            app.aoData.uVars.N                = app.NEditField.Value;
            
            uVars = app.aoData.uVars;

        end
        
        function fileSystem = collectAOFileSystemVars(app)
            app.aoData.uVars.fileSystem.saveRawData        = app.RawDataCheckBox.Value;
            app.aoData.uVars.fileSystem.saveNetSignal      = app.NetSignalCheckBox.Value;
            app.aoData.uVars.fileSystem.saveDemultiplexed  = app.DeMultiplexedCheckBox.Value;
            app.aoData.uVars.fileSystem.saveReshapedSignal = app.ReshapedCheckBox_2.Value;
            app.aoData.uVars.fileSystem.saveFFT            = app.FFTCheckBox_2.Value;
            app.aoData.uVars.fileSystem.savePhiChCmplx     = app.PhiChCmplxCheckBox.Value;
            
            app.aoData.uVars.fileSystem.saveResults = app.SaveResultsandVarsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveFigs    = app.SaveFigsCheckBox.Value;
            app.aoData.uVars.fileSystem.saveVars    = app.SaveResultsandVarsCheckBox.Value;
            
            app.aoData.uVars.fileSystem.scanName    = app.MeasurementNameEditField.Value;
            app.aoData.uVars.fileSystem.dirPath     = app.DirectoryEditField.Value;
            
            app.aoData.uVars.fileSystem.extProject         = false;
            app.aoData.uVars.fileSystem.extProjPath        = [];
            app.aoData.uVars.fileSystem.extProjResultsPath = [];
            app.aoData.uVars.fileSystem.extProjFigsPath    = [];
            
            fileSystem = app.aoData.uVars.fileSystem;
        end
    
        function figs = collectAOGraphicVars(app)
            % Choose channel and position            
            app.aoData.uVars.figs.ch    = app.PlotChannelSpinner.Value;
            app.aoData.uVars.figs.zIdx  = app.ZCoorAOSpinner.Value;
            app.aoData.uVars.figs.quant = app.QuantAOSpinner.Value;
            
            app.aoData.uVars.figs.FFTenv         = app.FreqEnviromentEditField.Value*1e3;
            app.aoData.uVars.figs.displayFullFFT = app.dispFullFFTCheckBox.Value;
            
            % Choose internal or external
            if app.FiguresinindependantwindowCheckBox.Value
                app.aoData.uVars.figs.intExt = 'int';
            else
                app.aoData.uVars.figs.intExt = 'ext';
            end
            
            % Turn figures on and off
            app.aoData.uVars.figs.validStruct.extClk         = app.ExternalClkCheckBox.Value;
            app.aoData.uVars.figs.validStruct.usSignal       = app.UltrasoundSignalCheckBox.Value;
            app.aoData.uVars.figs.validStruct.fullSignal     = app.FullSignalCheckBox.Value;
            app.aoData.uVars.figs.validStruct.measSamples    = app.MeasuredSamplesCheckBox.Value;
            app.aoData.uVars.figs.validStruct.netSignal      = app.NetSamplesCheckBox.Value;
            app.aoData.uVars.figs.validStruct.deMul          = app.DemultiplexedCheckBox.Value;
            app.aoData.uVars.figs.validStruct.reshapedSignal = app.ReshapedCheckBox_3.Value;
            app.aoData.uVars.figs.validStruct.FFT            = app.FFTCheckBox_3.Value;
            app.aoData.uVars.figs.validStruct.qAvgFFT        = app.QuantAvgFFTCheckBox.Value;
            app.aoData.uVars.figs.validStruct.phiCh          = app.PhiChCheckBox.Value;
            app.aoData.uVars.figs.validStruct.phi            = app.PhiCheckBox.Value;
            
            figs = app.aoData.uVars.figs;
            
        end
    
        function initAOVars(app)
            % These are static variables that shouldn't be changed during operation
            % These variables are initialized and never changed again.
            app.aoData.uVars.IOPort            = 1;
            app.aoData.uVars.IOLine            = 4;

            app.collectAOVars();
            app.collectAOGraphicVars();
            
            app.aoData.uVars.figs.fonts.titleSize  = 14;
            app.aoData.uVars.figs.fonts.labelsSize = 14;
            app.aoData.uVars.figs.fonts.axisSize   = 14;
            
            % Handles
            app.aoData.uVars.figs.extH.extClk.fig         = app.UIFigure;
            app.aoData.uVars.figs.extH.extClk.ax          = app.axExtClk;
            app.aoData.uVars.figs.extH.usSignal.fig       = app.UIFigure;
            app.aoData.uVars.figs.extH.usSignal.ax        = app.axUsSignal;
            app.aoData.uVars.figs.extH.fullSignal.fig     = app.UIFigure;
            app.aoData.uVars.figs.extH.fullSignal.ax      = app.axFullSignal;
            app.aoData.uVars.figs.extH.measSamples.fig    = app.UIFigure;
            app.aoData.uVars.figs.extH.measSamples.ax     = app.axMeasSig;
            app.aoData.uVars.figs.extH.netSignal.fig      = app.UIFigure;
            app.aoData.uVars.figs.extH.netSignal.ax       = app.axNetSignal;
            app.aoData.uVars.figs.extH.deMul.fig          = app.UIFigure;
            app.aoData.uVars.figs.extH.deMul.ax           = app.axDeMul;
            app.aoData.uVars.figs.extH.reshapedSignal.fig = app.UIFigure;
            app.aoData.uVars.figs.extH.reshapedSignal.ax  = app.axReshape;
            app.aoData.uVars.figs.extH.FFT.fig            = app.UIFigure;
            app.aoData.uVars.figs.extH.FFT.ax             = app.axFFTsm;
            app.aoData.uVars.figs.extH.qAvgFFT.fig        = app.UIFigure;
            app.aoData.uVars.figs.extH.qAvgFFT.ax         = app.axQAvgFFT;
            app.aoData.uVars.figs.extH.phiCh.fig          = app.UIFigure;
            app.aoData.uVars.figs.extH.phiCh.ax           = app.axPhiCh;
            app.aoData.uVars.figs.extH.phi.fig            = app.UIFigure;
            app.aoData.uVars.figs.extH.phi.ax             = app.axPhi;
        end
    
%         function deactivateAOfs(app)
%             %app.aoData.uVarsfileSystem.saveFullData    = false;
%             app.aoData.uVarsfileSystem.saveReducedData = false;
%             app.aoData.uVarsfileSystem.saveFigs        = false;
%             app.aoData.uVarsfileSystem.saveResults     = false;
%             
%             app.aoData.uVarsfileSystem.saveRawData        = false;
%             app.aoData.uVarsfileSystem.saveNetSignal      = false;
%             app.aoData.uVarsfileSystem.saveDemultiplexed  = false;
%             app.aoData.uVarsfileSystem.saveReshapedSignal = false;
%             app.aoData.uVarsfileSystem.saveFFT            = false;
%             app.aoData.uVarsfileSystem.savePhiChCmplx     = false;
%         end
        
        function setAOVarsToGUI(app, vars)
            app.SpeedinMediumEditField.Value  = vars.c;
            app.SineFrequencyEditField.Value  = vars.fSin/1e6;             
            app.TrainFrequencyEditField.Value = vars.fTrain/1e3;
            app.SinePeriodsEditField.Value    = vars.cycInPulse; 
            
            app.PhantomdepthEditField.Value       = vars.phantomDepth/1e-2;
            app.TransducerdistanceEditField.Value = vars.distFromPhantom/1e-2;
            app.FrequencyEditField.Value          = vars.fExtClk/1e6;
            app.TimetosampleEditField.Value       = vars.timeToSample;
            app.DutyCycleEditField.Value          = vars.extClkDcyc;
            app.FastAnalysisCheckBox.Value        = vars.fastAnalysis;
             
            app.UseTimeQuantCheckBox.Value    = vars.useQuant;
            app.QuantTimeEditField.Value      = vars.quantTime;
            app.UseHadamardCodeCheckBox.Value = vars.useHadamard;
            
            app.SclkEditField.Value             = vars.fSclk/1e6;
            
            app.NumberOfChannelsEditField.Value = vars.channels;
            
            app.UseGPUCheckBox.Value            = vars.useGPU;

        end
    
    
        %---------------- Consistency Functions ---------------%    
        function uVarsConsisInit(app)
           app.uVarsConsistency = Consistency.uVarsCreate();
           
           app.uVarsConsistency.gReq.extH.allFrames.fig  = app.UIFigure;
           app.uVarsConsistency.gReq.extH.allFrames.ax   = app.axAllFrames;
           app.uVarsConsistency.gReq.extH.curFrame.fig   = app.UIFigure;
           app.uVarsConsistency.gReq.extH.curFrame.ax    = app.axCurFrame;
           app.uVarsConsistency.gReq.extH.curSet.fig     = app.UIFigure;
           app.uVarsConsistency.gReq.extH.curSet.ax      = app.axCurSet;
           app.uVarsConsistency.gReq.extH.navFrame.fig   = app.UIFigure;
           app.uVarsConsistency.gReq.extH.navFrame.ax    = app.axNavFrame;
           app.uVarsConsistency.gReq.extH.navSet.fig     = app.UIFigure;
           app.uVarsConsistency.gReq.extH.navSet.ax      = app.axNavSet;
           
        end
    
        function uVarsConsisUpdate(app)
            
            app.collectAOVars;
            app.deactivateAOfs();
            app.uVarsConsistency.acoustoOptics = app.aoData.uVars;

            app.uVarsConsistency.stages.xPos = app.XpositionEditField.Value;
            app.uVarsConsistency.stages.yPos = app.YpositionEditField.Value;
            
            app.uVarsConsistency.fileSystem.scanName        = app.MeasurementNameEditField.Value;
            app.uVarsConsistency.fileSystem.resDirPath      = app.DirectoryEditField.Value;
            app.uVarsConsistency.fileSystem.saveFullData    = false;
            app.uVarsConsistency.fileSystem.saveReducedData = false;
            app.uVarsConsistency.fileSystem.saveFigs        = app.SaveFigsCheckBox.Value;
            app.uVarsConsistency.fileSystem.saveResults     = app.SaveResultsandVarsCheckBox.Value;
            
            app.uVarsConsistency.scan.startTime = app.MinTimeFrameDurationEditField.Value;
            app.uVarsConsistency.scan.stride    = app.TimeStrideEditField.Value;
            app.uVarsConsistency.scan.endTime   = app.MaxTimeFrameDurationEditField.Value;
            app.uVarsConsistency.scan.useQuant  = app.UseTimeQuantCheckBox.Value;
            app.uVarsConsistency.scan.quantTime = app.QuantTimeEditField.Value;
            app.uVarsConsistency.scan.numOfSets = app.SetsperTimeFrameEditField.Value;

            app.uVarsConsistency.gReq.ch    = app.PlotChannelSpinner.Value;
            app.uVarsConsistency.gReq.zIdx  = app.ZCoorConsSpinner.Value;
            app.uVarsConsistency.gReq.quant = 1;
            
            % Choose internal or external
            if app.FiguresinindependantwindowCheckBox.Value
                app.uVarsConsistency.gReq.intExt = 'int';
            else
                app.uVarsConsistency.gReq.intExt = 'ext';
            end

            names = fieldnames(app.uVarsConsistency.gReq.validStruct);
            for i=1:length(names)
                app.uVarsConsistency.gReq.validStruct.(names{i}) = true;
            end

        end

        %---------------- Scan 2D Functions ---------------%
        function uVarsS2dInit(app)
           app.uVarsS2d = s2d.uVarsCreate();
           
           app.uVarsS2d.gReq.extH.curMainAxis.fig     = app.UIFigure;
           app.uVarsS2d.gReq.extH.curMainAxis.ax      = app.axMainAxis;
           app.uVarsS2d.gReq.extH.curMainAxisRep.fig  = app.UIFigure;
           app.uVarsS2d.gReq.extH.curMainAxisRep.ax   = app.axMainAxisRep;
           app.uVarsS2d.gReq.extH.curMainPlain.fig    = app.UIFigure;
           app.uVarsS2d.gReq.extH.curMainPlain.ax     = app.axMainPlain;
           app.uVarsS2d.gReq.extH.curMainPlainRep.fig = app.UIFigure;
           app.uVarsS2d.gReq.extH.curMainPlainRep.ax  = app.axMainPlainRep;
           app.uVarsS2d.gReq.extH.navPlain.fig        = app.UIFigure;
           app.uVarsS2d.gReq.extH.navPlain.ax         = app.axNavPlain;
           app.uVarsS2d.gReq.extH.navPlainRep.fig     = app.UIFigure;
           app.uVarsS2d.gReq.extH.navPlainRep.ax      = app.axNavRep;
           
        end 

        function uVarsS2dUpdate(app)
            
            app.collectAOVars;
            app.deactivateAOfs(); %why? let's integrate!
            app.uVarsS2d.acoustoOptics = app.aoData.uVars;
            
            app.uVarsS2d.fileSystem.scanName        = app.MeasurementNameEditField.Value;
            app.uVarsS2d.fileSystem.resDirPath      = app.DirectoryEditField.Value;
%             app.uVarsS2d.fileSystem.saveFullData    = false;
%             app.uVarsS2d.fileSystem.saveReducedData = false;
            app.uVarsS2d.fileSystem.saveFigs        = app.SaveFigsCheckBox.Value;
            app.uVarsS2d.fileSystem.saveResults     = app.SaveResultsandVarsCheckBox.Value;
%             app.uVarsS2d.fileSystem.savePhiChCmplx  = app.PhiChCmplxCheckBox.Value;
            
            app.uVarsS2d.stages.startX  = app.XstartpointEditField.Value;
            app.uVarsS2d.stages.startY  = app.YstartpointEditField.Value;
            app.uVarsS2d.stages.endX    = app.XendpointEditField.Value;
            app.uVarsS2d.stages.endY    = app.YendPointEditField.Value;
            app.uVarsS2d.stages.strideX = app.XstrideEditField.Value;
            app.uVarsS2d.stages.strideY = app.YstrideEditField.Value;
            
            app.uVarsS2d.stages.firstAxis = app.MainAxisScanDropDown.Value;
            
            app.uVarsS2d.scan.useQuant = app.UseTimeQuantCheckBox.Value;
            app.uVarsS2d.scan.quantTime = app.QuantTimeEditField.Value;
            app.uVarsS2d.scan.timeToSample = app.TimetosampleEditField.Value;
            app.uVarsS2d.scan.repeats = app.RepeatsEditField.Value;

            app.uVarsS2d.gReq.ch    = app.PlotChannelSpinner.Value;
            app.uVarsS2d.gReq.zIdx  = app.ZCoorS2dSpinner.Value;
            app.uVarsS2d.gReq.quant = 1;
            
            % Choose internal or external
            if app.FiguresinindependantwindowCheckBox.Value
                app.uVarsS2d.gReq.intExt = 'int';
            else
                app.uVarsS2d.gReq.intExt = 'ext';
            end

            names = fieldnames(app.uVarsS2d.gReq.validStruct);
            for i=1:length(names)
                app.uVarsS2d.gReq.validStruct.(names{i}) = true;
            end

        end
   
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath(pwd));
            instrreset;
%             app.AOData.zVec = [];
%             app.AOData.numOfQuants = [];
            
            % --------- Algorithm ------- %
            app.algo = Algo();
            app.algoData.uVars = app.algo.uVarsCreate(); 
            app.initAlgoVars();
%             app.algoValues = app.algo.updateAlgoUserVars(app.uVarAlgo);

            %-------- Acousto Optics ---------%
            app.ao = acoustoOptics();
            app.aoGraphics = app.ao.getGraphicsHandle();
            app.aoData.uVars = app.ao.uVarsCreate();
            app.initAOVars();
            
            app.aoData.importedVars = [];
            app.aoData.importedRes  = [];
            app.aoData.curtVars     = [];
            app.aoData.curRes       = [];
            
            app.aoListeners{1} = listener(app,'timeTable',@app.displayAOTimeTable); 
            
            app.GUIctrl.restartAO = true;  % to init AO object in the beginning of the first run
            app.GUIctrl.liveAO    = false; % for toggling the button Run Live \ Stop Live
            
            %-------- Consistency ---------%
%             app.consistency = Consistency(app.ao, [], app);
%             app.hConsGraph  = app.consistency.getGraphicsHandle();
%             app.uVarsConsisInit();
%             
%             app.initConnsistency        = true;
%             app.consStagesRecon         = true;
% %             app.consData.quantDataValid = false;
%             app.consData.setDataValid   = false;
%             app.consData.frameDataValid = false;
%             app.consData.varsValid      = false;
%             
% %             app.consListeners{1} = listener(app,'updateQuant',@app.updateCurrentSetPlots);
%             app.consListeners{1} = listener(app,'updateSet',  @app.updateCurrentFramePlots);
%             app.consListeners{2} = listener(app,'updateFrame',@app.updateTotalFramePlots);
%             
%             %-------- Scan 2D --------%
%             app.s2d            = s2d(app.ao, [], app);
%             app.hS2dGraph      = app.s2d.getGraphicsHandle();
%             app.uVarsS2dInit();
%             %app.uVarsS2dUpdate();
%             %app.s2d.setUserVars(app.uVarsS2d);
%             
%             app.initS2d             = true;
%             app.s2dStagesRecon      = true;
%             app.s2dData.phiRepValid = false;
%             app.s2dData.phiValid    = false;
%             app.s2dData.varsValid   = false;
%             
%             app.s2dListeners{1} = listener(app,'updatePhiRepEvent',@app.updateS2DPlots);
%             app.s2dListeners{2} = listener(app,'updatePhiEvent',   @app.updateS2DPlotsAVG);
            
            %---- GUI Default Params ---- %
            app.updatePlotZSpinner();
            app.updateMainZSpinner();
            app.updateChannelSpinner();
            app.updateFrameAndSetSpinners();

        end

        % Button pushed function: RunButtonScan2D
        function RunButtonScan2DPushed(app, event)
            app.algoValues = app.algo.updateAlgoUserVars(app.algoData.uVars);
            app.uVarsS2dUpdate();
            
            if app.initS2d
                instrreset
                app.s2d.init();
                app.initS2d = false;
            elseif app.s2dStagesRecon
                instrreset
                app.s2d.restartStages();
            end
            
            app.s2dStagesRecon  = false;
            app.consStagesRecon = true;
            app.s2d.startScan(app.uVarsS2d);
            
        end

        % Button pushed function: StagesCalibToolButton
        function StagesCalibToolButtonPushed(app, event)
             app.s2dStagesRecon  = true;
             app.consStagesRecon = true;
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app);
        end

        % Button pushed function: ImportLimitsfromfileButton
        function ImportLimitsfromfileButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat');
            app.importedLimits = load([path, filename]);
            applyImportedLimits(app);
        end

        % Menu selected function: PMTSaturationInspector
        function PMTSaturationInspectorMenuSelected(app, event)
            PMTSat;
        end

        % Menu selected function: ScanAssistantMenu
        function ScanAssistantMenuSelected(app, event)
             h = stagesCalib(app);
             waitfor(h);
             applyImportedLimits(app)
        end

        % Value changing function: PlainIdxSpinner
        function PlainIdxSpinnerValueChanging(app, event)
            app.updatePlainCoorLabel();
            app.updateS2dNavigator();
        end

        % Value changed function: PlainDropDown
        function PlainDropDownValueChanged(app, event)
            app.updatePlaneIdxSelect();
            app.updateS2dNavigator();
        end

        % Value changed function: PlainIdxSpinner
        function PlainIdxSpinnerValueChanged(app, event)
            app.updatePlainCoorLabel();
            app.updateS2dNavigator();
        end

        % Value changed function: SamplingMethodDropDown
        function SamplingMethodDropDownValueChanged(app, event)
            value = app.SamplingMethodDropDown.Value;
            if strcmp(value ,'TS')
                set(app.TimetosampleEditField,'Enable','on');
                set(app.secLabel,'Enable','on');
                set(app.PeriodtoSampleEditField,'Enable','off');
                set(app.Label_2,'Enable','off');
            else
                set(app.TimetosampleEditField,'Enable','off');
                set(app.secLabel,'Enable','off');
                set(app.PeriodtoSampleEditField,'Enable','on');
                set(app.Label_2,'Enable','on');           
            end
        end

        % Button pushed function: ImportResultsButton
        function Import2DscanResultsButtonPushed(app, event)
%             [filename, path] = uigetfile('*.mat');
%             loadedData =  load([path, filename]);
%             if(~isfield(loadedData, 'I'))
%                msgbox({'Invalid File'; 'Can not detect field called I'} , 'Error','error');
%             elseif (~isfield(loadedData, 'params'))
%                 msgbox({'Invalid File'; 'Can not detect field called params'} , 'Error','error');
%             else
%                 app.scanData.I  = loadedData.I;
%                 app.Zaxis = loadedData.params.Sample.Zbar;
%                 app.Xaxis = loadedData.params.Stages.Xpos;
%                 app.Yaxis = loadedData.params.Stages.Ypos;
%                 app.Xplanes = size(app.scanData.I,2);
% 
%                 app.initNavigator();
%             end
            [path] = uigetdir('D:\Results\');
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path, '\Results.mat']);
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path, '\Vars.mat']);
            waitbar(1,h,'Done');
            close(h)
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            
            app.updateS2dGeneralData(...
                    vars.curVars.acoustoOptics.algoVars.len.zVecUSRes,...
                    vars.curVars.stages.xVec,...
                    vars.curVars.stages.yVec,...
                    vars.curVars.scan.repeats,...
                    vars.curVars.scan.numOfQuant);
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            notify(app, 'updatePhiRepEvent');
            notify(app, 'updatePhiEvent');
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.DirectoryEditField.Value = uigetdir('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements');
        end

        % Size changed function: ConsistencyTab
        function ConsistencyTabSizeChanged(app, event)

        end

        % Selection change function: MeasMethod
        function MeasMethodSelectionChanged(app, event)
            selectedTab = app.MeasMethod.SelectedTab;
            switch(selectedTab.Title)
                case 'Consistency'
                    app.ConsistencyPlotTab.Visible = true; 
                    app.ScanPlotTab.Visible = false;
                    app.MeasurementNameEditField.Value = 'Consistency';
                case 'Scan 2D'
                    app.ConsistencyPlotTab.Visible = false; 
                    app.ScanPlotTab.Visible = true;
                    app.MeasurementNameEditField.Value = '2DScan';
                case 'Single Measurement'
                    app.MeasurementNameEditField.Value = 'AcoustoOptics';
            end
        end

        % Menu selected function: DeactivatePeripheralsMenu
        function DeactivatePeripheralsMenuSelected(app, event)
            
        end

        % Menu selected function: ExtraFiguresMenu
        function ExtraFiguresMenuSelected(app, event)
            
        end

        % Button pushed function: RunButtonConsistency
        function RunButtonConsistencyPushed(app, event)
            app.algoValues = app.algo.updateAlgoUserVars(app.algoData.uVars);
            app.updatePlotZSpinner();
            app.uVarsConsisUpdate();
            
            
            if app.initConnsistency
                instrreset
                app.consistency.init();
                app.initConnsistency = false;
            elseif app.consStagesRecon
                instrreset % might be a problem with AFG
                app.consistency.restartStages();
            end
            app.s2dStagesRecon = true;
            app.consStagesRecon = false;
            app.consistency.startScan(app.uVarsConsistency);
        end

        % Value changed function: MaxTimeFrameDurationEditField
        function MaxTimeFrameDurationEditFieldValueChanged(app, event)
            
        end

        % Value changed function: MinTimeFrameDurationEditField
        function MinTimeFrameDurationEditFieldValueChanged(app, event)
       
        end

        % Value changed function: TimeStrideEditField
        function TimeStrideEditFieldValueChanged(app, event)

        end

        % Value changed function: SetsperTimeFrameEditField
        function SetsperTimeFrameEditFieldValueChanged(app, event)

        end

        % Value changed function: FrameToPlotSpinner
        function FrameToPlotSpinnerValueChanged(app, event)
            app.updateConsFrameLabel();
            app.updateNavFramePlots();
        end

        % Callback function
        function ZcoordinateSpinnerValueChanged(app, event)
           disp("Error - ZCoordinateSpinnerValueChanged function calles")
        end

        % Value changed function: SettoplotSpinner
        function SettoplotSpinnerValueChanged(app, event)
            app.updateNavSetPlots();
        end

        % Callback function
        function ZCoordinateSpinnerValueChanged(app, event)
           disp("Error - ZCoordinateSpinnerValueChanged function calles")
        end

        % Selection change function: GlobalParameters
        function GlobalParametersSelectionChanged(app, event)
            selectedTab = app.GlobalParameters.SelectedTab;
            if strcmp(selectedTab.Title, 'Problem Vars')
                app.collectAlgoVars()
                app.algoValues = app.algo.updateAlgoUserVars(app.algoData.uVars);
                app.setAlgoValuesTable();
            end
        end

        % Button pushed function: RunButtonAO
        function RunButtonAOPushed(app, event)
            if app.GUIctrl.restartAO
                app.ao.init();
                app.GUIctrl.restartAO = false;
            end
            app.collectAOVars();
            app.ao.setMeasVars(app.aoData.uVars);
            app.ao.configPeripherals();
            app.aoData.curVars = app.ao.getAOVars();
%             app.algoValues  = app.ao.algo.getVars();
%             app.AOData.zVec = app.algo.len.zVecUSRes;

            t = tic;
            if app.MeasureatcurrentposCheckBox.Value
                app.aoData.curRes = app.ao.measureAndAnalyse();
            else
                pos = [app.XpositionEditField_2.Value, app.YpositionEditField_2.Value];
                app.aoData.curRes = app.ao.moveMeasureAndAnalyse(pos);
            end
            t = toc(t);
            
            timeTable = app.ao.getTimeTable();
            dataAlgo  = app.extractStructDataToTable(timeTable.algo);
            dataDigi  = app.extractStructDataToTable(timeTable.digitizer);
            dataAO(1,1:2) = {'Overall',t}; 
            dataAO(2,1:2) = {'Overall Sampling',timeTable.acq}; 
            dataAO(3,1:2) = {'Overall Analysis',timeTable.analyse}; 
            dataAO(4,1:2) = {'Gather Raw Data', timeTable.moveData};
    
            app.UITable2.Data   = dataAlgo(:,1:2);
            app.UITable2_2.Data = dataDigi(:,1:2);
            app.UITable2_3.Data = dataAO;
        end

        % Callback function
        function InButtonPushed(app, event)
            zoom(ax, 'on')
        end

        % Callback function
        function ZoomOutButtonPushed(app, event)
            zoom(ax, 'out')
        end

        % Button pushed function: setUpperLimits
        function setUpperLimitsButtonPushed(app, event)
        if strcmp(app.acoustoOpticsPlotsTab.SelectedTab, 'Time Table')
                return
        end
        ax = app.acoustoOpticsPlotsTab.SelectedTab.Children;
        if strcmp(ax.Type, 'axes')
            xlim(ax, [app.StartLimitUpper.Value, app.EndLimitUpper.Value]);
        end
        end

        % Button pushed function: setLowerLimits
        function setLowerLimitsButtonPushed(app, event)
            ax = app.SingleMeasurementPlots_2.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, [app.StartLimitLower.Value, app.EndLimitLower.Value]);
            end
        end

        % Button pushed function: resetUpperAxLimits
        function resetUpperAxLimitsButtonPushed(app, event)
            if strcmp(app.acoustoOpticsPlotsTab.SelectedTab, 'Time Table')
                return
            end
            ax = app.acoustoOpticsPlotsTab.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, 'auto');
                ylim(ax, 'auto');
            end
        end

        % Button pushed function: resetLowerAxLimits
        function resetLowerAxLimitsButtonPushed(app, event)
            if app.ScanPlotTab.Visible
                panel = app.ScanPlotTab;
            elseif app.ConsistencyPlotTab.Visible
                panel = app.ConsistencyPlotTab;
            else
                disp("No visible plot")
                return
            end
            ax = panel.SelectedTab.Children;
            if strcmp(ax.Type, 'axes')
                xlim(ax, 'auto');
                ylim(ax, 'auto');
            end
        end

        % Button pushed function: TurnOffAFGButton
        function TurnOffAFGButtonPushed(app, event)
            app.ao.fGen.disableAllOutputs();
        end

        % Value changed function: NumberOfChannelsEditField
        function NumberOfChannelsEditFieldValueChanged(app, event)
            updateChannelPlotSpinner()
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            app.algoValues = app.algo.updateAlgoUserVars(app.algoData.uVars);
            app.updatePlotZSpinner();
        end

        % Value changed function: SineFrequencyEditField
        function SineFrequencyEditFieldValueChanged(app, event)
            app.algoValues = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: SinePeriodsEditField
        function SinePeriodsEditFieldValueChanged(app, event)
            app.algoValues = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: TrainFrequencyEditField
        function TrainFrequencyEditFieldValueChanged(app, event)
            app.algoValues = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner(); 
        end

        % Value changed function: SpeedinMediumEditField
        function SpeedinMediumEditFieldValueChanged(app, event)
            app.algoValues = app.algo.updateAlgoUserVars(app.algoData.uVars);
%             app.updatePlotZSpinner();
        end

        % Value changed function: ZCoorConsSpinner
        function ZCoorConsSpinnerValueChanged(app, event)
            app.updateConsZLabel();
            app.updateConsChAndPos();
        end

        % Value changed function: PlotChannelSpinner
        function PlotChannelSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: ZCoorS2dSpinner
        function ZCoorS2dSpinnerValueChanged(app, event)
            app.updateMainAxisCoorLabel();
            app.updateS2dChAndPos();
        end

        % Value changed function: ReapeatSpinner
        function ReapeatSpinnerValueChanged(app, event)
            app.updateS2dNavigator();
        end

        % Value changed function: RepeatsEditField
        function RepeatsEditFieldValueChanged(app, event)
            
        end

        % Value changing function: ZCoorS2dSpinner
        function ZCoorS2dSpinnerValueChanging(app, event)
            app.updateMainAxisCoorLabel();
            app.updateS2dChAndPos();
        end

        % Value changed function: ZCoorAOSpinner
        function ZCoorAOSpinnerValueChanged(app, event)
            app.updateAOZLabel();
            app.updateAOFigs();
        end

        % Value changing function: ZCoorConsSpinner
        function ZCoorConsSpinnerValueChanging(app, event)
            app.updateConsZLabel();
            app.updateConsChAndPos();
        end

        % Button pushed function: RunLiveButton
        function RunLiveButtonPushed(app, event)
            if app.GUIctrl.liveAO 
                fprintf("GUI: Stopping Live AO, Please wait...\n");
                app.ao.runLive = false;
                app.RunLiveButton.Text = 'Run Live';
                app.GUIctrl.liveAO = false;
            else
                if app.GUIctrl.restartAO
                    app.ao.init();
                    app.GUIctrl.restartAO = false;
                end
                app.collectAOVars();
                
                app.ao.setMeasVars(app.aoData.uVars);
                app.ao.configPeripherals();
                
                % For spinner update:
                app.aoData.curVars = app.ao.getAOVars();
                %insert function that update the spiner limits
                
                app.RunLiveButton.Text = 'Stop Live';
                
                app.GUIctrl.liveAO = true;
                app.aoData.curRes = app.ao.liveAcoustoOptics();
                app.RunLiveButton.Text = 'Run Live';
                app.GUIctrl.liveAO = false;
            end
        end

        % Callback function
        function ImportResultsButtonPushed(app, event)
            [path] = uigetdir('D:\Results\');
            h = waitbar(0,'Loading Results. Please wait...');
            data = load([path, '\Results']);
            waitbar(.5,h,'Loading Variables. Please wait');
            vars = load([path, '\Vars']);
            waitbar(1,h,'Done');
            close(h)
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            
            app.updateS2dGeneralData(...
                    vars.curVars.acoustoOptics.algoVars.len.zVecUSRes,...
                    vars.curVars.stages.xVec,...
                    vars.curVars.stages.yVec,...
                    vars.curVars.scan.repeats,...
                    vars.curVars.scan.numOfQuant);
            
            app.updatePhiRep(data.res.phiRep, data.res.phiRepStd, ones(1,3));
            app.updatePhi(data.res.phi, data.res.phiStd);
            notify(app, 'updatePhiRepEvent');
            notify(app, 'updatePhiEvent');
            
        end

        % Button pushed function: CloseIOPortButton
        function CloseIOPortButtonPushed(app, event)
            app.ao.IO.close();
            fprintf("Closing IO\n")
        end

        % Button pushed function: analysisDataPathButton
        function analysisDataPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.DataEditField.Value = [path, filename];
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Callback function
        function UsesaveddatafornoHWdebugCheckBoxValueChanged(app, event)
                
        end

        % Button pushed function: analysisVarsPathButton
        function analysisVarsPathButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            app.VarsEditField.Value = [path, filename]; 
            app.AnalyzeOrigButton.Enable = false;
            app.AnalyizeNewButton.Enable = false;
        end

        % Value changed function: MeasureatcurrentposCheckBox
        function MeasureatcurrentposCheckBoxValueChanged(app, event)
            value = app.MeasureatcurrentposCheckBox.Value;
            if value
                app.XpositionEditField_2.Enable = false;
                app.YpositionEditField_2.Enable = false;
            else
                app.XpositionEditField_2.Enable = true;
                app.YpositionEditField_2.Enable = true;
            end
        end

        % Value changed function: LimitLiveAOCheckBox
        function LimitLiveAOCheckBoxValueChanged(app, event)
            value = app.LimitLiveAOCheckBox.Value;
            if value
                app.NEditField.Enable = true;
            else
                app.NEditField.Enable = false;
            end
        end

        % Button pushed function: ExportVarsButton
        function ExportVarsButtonPushed(app, event)
            app.collectAOVars();
            % Saved as field called uVars in order to comply with AO vars saving procedure.
            vars.uVars = app.aoData.uVars;
            vars.uVars.figs = [];
            [filename, path] = uiputfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements', '*.mat');
            save([path, filename], '-struct', 'vars', '-v7.3');
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            rawDataPath = app.DataEditField.Value;
            varsPath    = app.VarsEditField.Value;
            fprintf("GUI: Trying to results and vars\n");
            rawData     = app.ao.loadRawDataToAO(rawDataPath);
            vars        = load(varsPath);
            if ~isempty(rawData) && ~isempty(vars) 
                app.AnalyzeOrigButton.Enable = true;
                app.AnalyizeNewButton.Enable = true;
                app.aoData.importedVars = vars;
                app.aoData.importedData = rawData;
                app.setAOVarsToGUI(vars.uVars);
                fprintf("GUI: Successfully loaded results and vars.\n");
            else
                app.AnalyzeOrigButton.Enable = false;
                app.AnalyizeNewButton.Enable = false;
                fprintf("GUI: Invalid files\n");
            end
        end

        % Button pushed function: ImportVarsButton
        function ImportVarsButtonPushed(app, event)
            [filename, path] = uigetfile('C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements','*.mat');
            vars = load([path,filename]);
            if isfield(vars, 'uVars')
                app.setAOVarsToGUI(vars.uVars); 
            else
                app.setAOVarsToGUI(vars);
            end
        end

        % Button pushed function: AnalyzeOrigButton
        function AnalyzeOrigButtonPushed(app, event)
            vars = app.aoData.importedVars;
            
            app.setAOVarsToGUI(app.aoData.importedVars.uVars); %not sure this is convinient to the user
            
            app.collectAOGraphicVars();
            app.collectAOFileSystemVars();
             
            vars.uVars.figs       = app.aoData.uVars.figs; %plot analysed data according to user request
            vars.uVars.fileSystem = app.aoData.uVars.fileSystem; %save analysed data according to user request
            
            app.ao.setMeasVars(vars.uVars);
            app.aoData.curRes  = app.ao.analyseLoadedData();
            app.aoData.curVars = app.ao.getAOVars();
        end

        % Button pushed function: AnalyizeNewButton
        function AnalyizeNewButtonPushed(app, event)
            app.collectAOVars();
            app.ao.setMeasVars(app.aoData.uVars);
            app.aoData.curRes  = app.ao.analyseLoadedData();
            app.aoData.curVars = app.ao.getAOVars();
        end

        % Value changed function: QuantAOSpinner
        function QuantAOSpinnerValueChanged(app, event)
            app.updateAOFigs();
        end

        % Value changed function: FreqEnviromentEditField
        function FreqEnviromentEditFieldValueChanged(app, event)
            app.updateAOFigs();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadCondifurationsMenu = uimenu(app.FileMenu);
            app.LoadCondifurationsMenu.Text = 'Load Condifurations';
            app.SaveConfigurationsMenu = uimenu(app.FileMenu);
            app.SaveConfigurationsMenu.Text = 'Save Configurations';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.DebugMenu = uimenu(app.ToolsMenu);
            app.DebugMenu.Text = 'Debug';
            app.DeactivatePeripheralsMenu = uimenu(app.DebugMenu);
            app.DeactivatePeripheralsMenu.MenuSelectedFcn = createCallbackFcn(app, @DeactivatePeripheralsMenuSelected, true);
            app.DeactivatePeripheralsMenu.Text = 'Deactivate Peripherals ';
            app.ExtraFiguresMenu = uimenu(app.DebugMenu);
            app.ExtraFiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtraFiguresMenuSelected, true);
            app.ExtraFiguresMenu.Text = 'Extra Figures';
            app.PMTSaturationInspector = uimenu(app.ToolsMenu);
            app.PMTSaturationInspector.MenuSelectedFcn = createCallbackFcn(app, @PMTSaturationInspectorMenuSelected, true);
            app.PMTSaturationInspector.Text = 'PMT Saturation Inspector';
            app.ScanAssistantMenu = uimenu(app.ToolsMenu);
            app.ScanAssistantMenu.MenuSelectedFcn = createCallbackFcn(app, @ScanAssistantMenuSelected, true);
            app.ScanAssistantMenu.Text = 'Scan Assistant';
            app.FindMyinstrumentPortMenu = uimenu(app.ToolsMenu);
            app.FindMyinstrumentPortMenu.Text = 'Find My instrument Port';
            app.InstrumentsMenu = uimenu(app.UIFigure);
            app.InstrumentsMenu.Text = 'Instruments';
            app.StagesMenu = uimenu(app.InstrumentsMenu);
            app.StagesMenu.Text = 'Stages';
            app.ScanningAssistantMenu = uimenu(app.StagesMenu);
            app.ScanningAssistantMenu.Text = 'Scanning Assistant';
            app.AFGMenu = uimenu(app.InstrumentsMenu);
            app.AFGMenu.Text = 'AFG';
            app.OscilloscopeMenu = uimenu(app.InstrumentsMenu);
            app.OscilloscopeMenu.Text = 'Oscilloscope';
            app.ResetInstrumentsCommunicationMenu = uimenu(app.InstrumentsMenu);
            app.ResetInstrumentsCommunicationMenu.Text = 'Reset Instruments Communication';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.AcoustoOpticSystemLabel = uilabel(app.UIFigure);
            app.AcoustoOpticSystemLabel.Text = 'AcoustoOptic System';
            app.ScanPlotTab = uitabgroup(app.UIFigure);
            app.MainAxisTab = uitab(app.ScanPlotTab);
            app.MainAxisTab.Title = 'Main Axis';
            app.axMainAxis = uiaxes(app.MainAxisTab);
            title(app.axMainAxis, 'Title')
            xlabel(app.axMainAxis, 'X')
            ylabel(app.axMainAxis, 'Y')
            app.MainPlainTab = uitab(app.ScanPlotTab);
            app.MainPlainTab.Title = 'Main Plain';
            app.axMainPlain = uiaxes(app.MainPlainTab);
            title(app.axMainPlain, 'Title')
            xlabel(app.axMainPlain, 'X')
            ylabel(app.axMainPlain, 'Y')
            app.NavigatorTab = uitab(app.ScanPlotTab);
            app.NavigatorTab.Title = 'Navigator';
            app.axNavPlain = uiaxes(app.NavigatorTab);
            title(app.axNavPlain, 'Title')
            xlabel(app.axNavPlain, 'X')
            ylabel(app.axNavPlain, 'Y')
            app.MainAxisAveragedTab = uitab(app.ScanPlotTab);
            app.MainAxisAveragedTab.Title = 'Main Axis (Averaged)';
            app.axMainAxisRep = uiaxes(app.MainAxisAveragedTab);
            title(app.axMainAxisRep, 'Title')
            xlabel(app.axMainAxisRep, 'X')
            ylabel(app.axMainAxisRep, 'Y')
            app.MainPlainAveragedTab = uitab(app.ScanPlotTab);
            app.MainPlainAveragedTab.Title = 'Main Plain (Averaged)';
            app.axMainPlainRep = uiaxes(app.MainPlainAveragedTab);
            title(app.axMainPlainRep, 'Title')
            xlabel(app.axMainPlainRep, 'X')
            ylabel(app.axMainPlainRep, 'Y')
            app.NavigatorAveragedTab = uitab(app.ScanPlotTab);
            app.NavigatorAveragedTab.Title = 'Navigator (Averaged)';
            app.axNavRep = uiaxes(app.NavigatorAveragedTab);
            title(app.axNavRep, 'Title')
            xlabel(app.axNavRep, 'X')
            ylabel(app.axNavRep, 'Y')
            app.SimulationNamePanel = uipanel(app.UIFigure);
            app.SimulationNamePanel.Title = 'Simulation Name';
            app.MeasurementNameEditFieldLabel = uilabel(app.SimulationNamePanel);
            app.MeasurementNameEditFieldLabel.Text = 'Measurement Name';
            app.MeasurementNameEditField = uieditfield(app.SimulationNamePanel, 'text');
            app.MeasurementNameEditField.Value = 'Scan';
            app.DirectoryEditFieldLabel = uilabel(app.SimulationNamePanel);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.SimulationNamePanel, 'text');
            app.DirectoryEditField.Value = 'C:\Users\ahiad\OneDrive - Technion\Graduate\AcoustoOpticSystem\Measurements';
            app.SaveResultsandVarsCheckBox = uicheckbox(app.SimulationNamePanel);
            app.SaveResultsandVarsCheckBox.Text = 'Save Results and Vars';
            app.SaveFigsCheckBox = uicheckbox(app.SimulationNamePanel);
            app.SaveFigsCheckBox.Text = 'Save Figs';
            app.RawDataCheckBox = uicheckbox(app.SimulationNamePanel);
            app.RawDataCheckBox.Text = 'Raw Data';
            app.NetSignalCheckBox = uicheckbox(app.SimulationNamePanel);
            app.NetSignalCheckBox.Text = 'Net Signal';
            app.DeMultiplexedCheckBox = uicheckbox(app.SimulationNamePanel);
            app.DeMultiplexedCheckBox.Text = 'DeMultiplexed';
            app.ReshapedCheckBox_2 = uicheckbox(app.SimulationNamePanel);
            app.ReshapedCheckBox_2.Text = 'Reshaped';
            app.FFTCheckBox_2 = uicheckbox(app.SimulationNamePanel);
            app.FFTCheckBox_2.Text = 'FFT';
            app.SaveSpecificDataLabel = uilabel(app.SimulationNamePanel);
            app.SaveSpecificDataLabel.Text = 'Save Specific Data:';
            app.PhiChCmplxCheckBox = uicheckbox(app.SimulationNamePanel);
            app.PhiChCmplxCheckBox.Text = 'Phi Ch Cmplx';
            app.Button = uibutton(app.SimulationNamePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.MeasMethod = uitabgroup(app.UIFigure);
            app.MeasMethod.SelectionChangedFcn = createCallbackFcn(app, @MeasMethodSelectionChanged, true);
            app.SingleMeasurementTab = uitab(app.MeasMethod);
            app.SingleMeasurementTab.Title = 'Single Measurement';
            app.RunButtonAO = uibutton(app.SingleMeasurementTab, 'push');
            app.RunButtonAO.ButtonPushedFcn = createCallbackFcn(app, @RunButtonAOPushed, true);
            app.RunButtonAO.Text = 'Run';
            app.RunLiveButton = uibutton(app.SingleMeasurementTab, 'push');
            app.RunLiveButton.ButtonPushedFcn = createCallbackFcn(app, @RunLiveButtonPushed, true);
            app.RunLiveButton.Text = 'Run Live';
            app.StagesPanel_3 = uipanel(app.SingleMeasurementTab);
            app.StagesPanel_3.Title = 'Stages';
            app.mmLabel_17 = uilabel(app.StagesPanel_3);
            app.mmLabel_17.Text = '[mm]';
            app.mmLabel_18 = uilabel(app.StagesPanel_3);
            app.mmLabel_18.Text = '[mm]';
            app.XpositionEditField_2Label = uilabel(app.StagesPanel_3);
            app.XpositionEditField_2Label.Enable = 'off';
            app.XpositionEditField_2Label.Text = 'X position';
            app.XpositionEditField_2 = uieditfield(app.StagesPanel_3, 'numeric');
            app.XpositionEditField_2.Enable = 'off';
            app.XpositionEditField_2.Value = 124;
            app.YpositionEditField_2Label = uilabel(app.StagesPanel_3);
            app.YpositionEditField_2Label.Enable = 'off';
            app.YpositionEditField_2Label.Text = 'Y position';
            app.YpositionEditField_2 = uieditfield(app.StagesPanel_3, 'numeric');
            app.YpositionEditField_2.Enable = 'off';
            app.YpositionEditField_2.Value = 90;
            app.MeasureatcurrentposCheckBox = uicheckbox(app.StagesPanel_3);
            app.MeasureatcurrentposCheckBox.ValueChangedFcn = createCallbackFcn(app, @MeasureatcurrentposCheckBoxValueChanged, true);
            app.MeasureatcurrentposCheckBox.Text = 'Measure at current pos';
            app.MeasureatcurrentposCheckBox.Value = true;
            app.LiveAcoustoOpticsPanel = uipanel(app.SingleMeasurementTab);
            app.LiveAcoustoOpticsPanel.Title = 'Live Acousto Optics';
            app.LimitLiveAOCheckBox = uicheckbox(app.LiveAcoustoOpticsPanel);
            app.LimitLiveAOCheckBox.ValueChangedFcn = createCallbackFcn(app, @LimitLiveAOCheckBoxValueChanged, true);
            app.LimitLiveAOCheckBox.Text = 'LimitLiveAO';
            app.LimitLiveAOCheckBox.Value = true;
            app.NEditFieldLabel = uilabel(app.LiveAcoustoOpticsPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.LiveAcoustoOpticsPanel, 'numeric');
            app.NEditField.Limits = [1 Inf];
            app.NEditField.Value = 1;
            app.ImportsavedResultsPanel = uipanel(app.SingleMeasurementTab);
            app.ImportsavedResultsPanel.Title = 'Import saved Results';
            app.DataEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.ImportsavedResultsPanel, 'text');
            app.DataEditField.Editable = 'off';
            app.analysisDataPathButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.analysisDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisDataPathButtonPushed, true);
            app.analysisDataPathButton.Text = '...';
            app.AnalyzeOrigButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.AnalyzeOrigButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeOrigButtonPushed, true);
            app.AnalyzeOrigButton.Enable = 'off';
            app.AnalyzeOrigButton.Tooltip = {'Analyze loaded data with original variables.'};
            app.AnalyzeOrigButton.Text = 'Analyze Orig';
            app.VarsEditFieldLabel = uilabel(app.ImportsavedResultsPanel);
            app.VarsEditFieldLabel.Text = 'Vars';
            app.VarsEditField = uieditfield(app.ImportsavedResultsPanel, 'text');
            app.VarsEditField.Editable = 'off';
            app.analysisVarsPathButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.analysisVarsPathButton.ButtonPushedFcn = createCallbackFcn(app, @analysisVarsPathButtonPushed, true);
            app.analysisVarsPathButton.Text = '...';
            app.ImportButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.AnalyizeNewButton = uibutton(app.ImportsavedResultsPanel, 'push');
            app.AnalyizeNewButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyizeNewButtonPushed, true);
            app.AnalyizeNewButton.Enable = 'off';
            app.AnalyizeNewButton.Tooltip = {'Analyse with variables appear in this GUI'};
            app.AnalyizeNewButton.Text = 'Analyize New';
            app.ImportVarsButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ImportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportVarsButtonPushed, true);
            app.ImportVarsButton.Text = 'Import Vars';
            app.ExportVarsButton = uibutton(app.SingleMeasurementTab, 'push');
            app.ExportVarsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVarsButtonPushed, true);
            app.ExportVarsButton.Text = 'Export Vars';
            app.ConsistencyTab = uitab(app.MeasMethod);
            app.ConsistencyTab.SizeChangedFcn = createCallbackFcn(app, @ConsistencyTabSizeChanged, true);
            app.ConsistencyTab.Title = 'Consistency';
            app.RunButtonConsistency = uibutton(app.ConsistencyTab, 'push');
            app.RunButtonConsistency.ButtonPushedFcn = createCallbackFcn(app, @RunButtonConsistencyPushed, true);
            app.RunButtonConsistency.Text = 'Run';
            app.StagesPanel_2 = uipanel(app.ConsistencyTab);
            app.StagesPanel_2.Title = 'Stages';
            app.mmLabel_10 = uilabel(app.StagesPanel_2);
            app.mmLabel_10.Text = '[mm]';
            app.mmLabel_11 = uilabel(app.StagesPanel_2);
            app.mmLabel_11.Text = '[mm]';
            app.XpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.XpositionEditFieldLabel.Text = 'X position';
            app.XpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.XpositionEditField.Value = 124;
            app.YpositionEditFieldLabel = uilabel(app.StagesPanel_2);
            app.YpositionEditFieldLabel.Text = 'Y position';
            app.YpositionEditField = uieditfield(app.StagesPanel_2, 'numeric');
            app.YpositionEditField.Value = 90;
            app.TimeFramePropertiesPanel = uipanel(app.ConsistencyTab);
            app.TimeFramePropertiesPanel.Title = 'Time Frame Properties';
            app.sLabel = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel.Text = '[s]';
            app.sLabel_2 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_2.Text = '[s]';
            app.sLabel_3 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_3.Text = '[s]';
            app.sLabel_4 = uilabel(app.TimeFramePropertiesPanel);
            app.sLabel_4.Text = '[s]';
            app.MaxTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MaxTimeFrameDurationEditFieldLabel.Text = 'Max Time Frame Duration';
            app.MaxTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MaxTimeFrameDurationEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeFrameDurationEditFieldValueChanged, true);
            app.MaxTimeFrameDurationEditField.Value = 8;
            app.MinTimeFrameDurationEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.MinTimeFrameDurationEditFieldLabel.Text = 'Min Time Frame Duration';
            app.MinTimeFrameDurationEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.MinTimeFrameDurationEditField.ValueChangedFcn = createCallbackFcn(app, @MinTimeFrameDurationEditFieldValueChanged, true);
            app.MinTimeFrameDurationEditField.Value = 2;
            app.TimeStrideEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.TimeStrideEditFieldLabel.Text = 'Time Stride';
            app.TimeStrideEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.TimeStrideEditField.ValueChangedFcn = createCallbackFcn(app, @TimeStrideEditFieldValueChanged, true);
            app.TimeStrideEditField.Value = 2;
            app.SetsperTimeFrameEditFieldLabel = uilabel(app.TimeFramePropertiesPanel);
            app.SetsperTimeFrameEditFieldLabel.Text = 'Sets per Time Frame';
            app.SetsperTimeFrameEditField = uieditfield(app.TimeFramePropertiesPanel, 'numeric');
            app.SetsperTimeFrameEditField.ValueChangedFcn = createCallbackFcn(app, @SetsperTimeFrameEditFieldValueChanged, true);
            app.SetsperTimeFrameEditField.Value = 20;
            app.ImportResultsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ImportResultsButton_2.Enable = 'off';
            app.ImportResultsButton_2.Text = 'Import Results';
            app.ExportScanVarsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ExportScanVarsButton_2.Text = 'Export Scan Vars';
            app.ImportScanVarsButton_2 = uibutton(app.ConsistencyTab, 'push');
            app.ImportScanVarsButton_2.Text = 'Import Scan Vars';
            app.FiguresPanel_2 = uipanel(app.ConsistencyTab);
            app.FiguresPanel_2.Title = 'Figures';
            app.FrameToPlotSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.FrameToPlotSpinnerLabel.Text = 'Frame To Plot';
            app.FrameToPlotSpinner = uispinner(app.FiguresPanel_2);
            app.FrameToPlotSpinner.Limits = [1 2];
            app.FrameToPlotSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameToPlotSpinnerValueChanged, true);
            app.FrameToPlotSpinner.Value = 1;
            app.SettoplotSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.SettoplotSpinnerLabel.Text = 'Set to plot';
            app.SettoplotSpinner = uispinner(app.FiguresPanel_2);
            app.SettoplotSpinner.Limits = [1 2];
            app.SettoplotSpinner.ValueChangedFcn = createCallbackFcn(app, @SettoplotSpinnerValueChanged, true);
            app.SettoplotSpinner.Value = 1;
            app.ZCoorSpinnerLabel = uilabel(app.FiguresPanel_2);
            app.ZCoorSpinnerLabel.Text = 'Z Coor';
            app.ZCoorConsSpinner = uispinner(app.FiguresPanel_2);
            app.ZCoorConsSpinner.ValueChangingFcn = createCallbackFcn(app, @ZCoorConsSpinnerValueChanging, true);
            app.ZCoorConsSpinner.ValueChangedFcn = createCallbackFcn(app, @ZCoorConsSpinnerValueChanged, true);
            app.ZCoorConsSpinner.Value = 1;
            app.sLabel_6 = uilabel(app.FiguresPanel_2);
            app.sLabel_6.Text = '0 [s]';
            app.mmLabel_15 = uilabel(app.FiguresPanel_2);
            app.mmLabel_15.Text = '00.00[mm]';
            app.Scan2DTab = uitab(app.MeasMethod);
            app.Scan2DTab.Title = 'Scan 2D';
            app.StagesPanel = uipanel(app.Scan2DTab);
            app.StagesPanel.Title = 'Stages';
            app.StagesCalibToolButton = uibutton(app.StagesPanel, 'push');
            app.StagesCalibToolButton.ButtonPushedFcn = createCallbackFcn(app, @StagesCalibToolButtonPushed, true);
            app.StagesCalibToolButton.Text = 'Stages Calib Tool';
            app.mmLabel_4 = uilabel(app.StagesPanel);
            app.mmLabel_4.Text = '[mm]';
            app.mmLabel_5 = uilabel(app.StagesPanel);
            app.mmLabel_5.Text = '[mm]';
            app.mmLabel_6 = uilabel(app.StagesPanel);
            app.mmLabel_6.Text = '[mm]';
            app.mmLabel_7 = uilabel(app.StagesPanel);
            app.mmLabel_7.Text = '[mm]';
            app.mmLabel_8 = uilabel(app.StagesPanel);
            app.mmLabel_8.Text = '[mm]';
            app.mmLabel_9 = uilabel(app.StagesPanel);
            app.mmLabel_9.Text = '[mm]';
            app.XstartpointEditField_2Label = uilabel(app.StagesPanel);
            app.XstartpointEditField_2Label.Text = 'X start point';
            app.XstartpointEditField = uieditfield(app.StagesPanel, 'numeric');
            app.XstartpointEditField.Value = 126;
            app.XendpointEditField_2Label = uilabel(app.StagesPanel);
            app.XendpointEditField_2Label.Text = 'X end point';
            app.XendpointEditField = uieditfield(app.StagesPanel, 'numeric');
            app.XendpointEditField.Value = 124;
            app.YstartpointEditField_2Label = uilabel(app.StagesPanel);
            app.YstartpointEditField_2Label.Text = 'Y start point';
            app.YstartpointEditField = uieditfield(app.StagesPanel, 'numeric');
            app.YstartpointEditField.Value = 85;
            app.YendPointEditField_2Label = uilabel(app.StagesPanel);
            app.YendPointEditField_2Label.Text = 'Y end Point';
            app.YendPointEditField = uieditfield(app.StagesPanel, 'numeric');
            app.YendPointEditField.Value = 100;
            app.XstrideEditFieldLabel = uilabel(app.StagesPanel);
            app.XstrideEditFieldLabel.Text = 'X stride';
            app.XstrideEditField = uieditfield(app.StagesPanel, 'numeric');
            app.XstrideEditField.Value = -0.5;
            app.YstrideEditFieldLabel = uilabel(app.StagesPanel);
            app.YstrideEditFieldLabel.Text = 'Y stride';
            app.YstrideEditField = uieditfield(app.StagesPanel, 'numeric');
            app.YstrideEditField.Value = 0.5;
            app.MainAxisScanDropDownLabel = uilabel(app.StagesPanel);
            app.MainAxisScanDropDownLabel.Text = 'Main Axis Scan';
            app.MainAxisScanDropDown = uidropdown(app.StagesPanel);
            app.MainAxisScanDropDown.Items = {'Y', 'X'};
            app.MainAxisScanDropDown.Value = 'Y';
            app.ImportLimitsfromfileButton = uibutton(app.StagesPanel, 'push');
            app.ImportLimitsfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportLimitsfromfileButtonPushed, true);
            app.ImportLimitsfromfileButton.Text = 'Import Limits from file';
            app.RunButtonScan2D = uibutton(app.Scan2DTab, 'push');
            app.RunButtonScan2D.ButtonPushedFcn = createCallbackFcn(app, @RunButtonScan2DPushed, true);
            app.RunButtonScan2D.Text = 'Run';
            app.RepeatsPanel = uipanel(app.Scan2DTab);
            app.RepeatsPanel.Title = 'Repeats';
            app.RepeatsEditFieldLabel = uilabel(app.RepeatsPanel);
            app.RepeatsEditFieldLabel.Text = 'Repeats';
            app.RepeatsEditField = uieditfield(app.RepeatsPanel, 'numeric');
            app.RepeatsEditField.ValueChangedFcn = createCallbackFcn(app, @RepeatsEditFieldValueChanged, true);
            app.RepeatsEditField.Value = 1;
            app.ImportResultsButton = uibutton(app.Scan2DTab, 'push');
            app.ImportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @Import2DscanResultsButtonPushed, true);
            app.ImportResultsButton.Text = 'Import Results';
            app.ExportScanVarsButton = uibutton(app.Scan2DTab, 'push');
            app.ExportScanVarsButton.Text = 'Export Scan Vars';
            app.ImportScanVarsButton = uibutton(app.Scan2DTab, 'push');
            app.ImportScanVarsButton.Text = 'Import Scan Vars';
            app.FiguresPanel = uipanel(app.Scan2DTab);
            app.FiguresPanel.Title = 'Figures';
            app.PlainDropDownLabel = uilabel(app.FiguresPanel);
            app.PlainDropDownLabel.Text = 'Plain';
            app.PlainDropDown = uidropdown(app.FiguresPanel);
            app.PlainDropDown.Items = {'X', 'Y', 'Z'};
            app.PlainDropDown.ValueChangedFcn = createCallbackFcn(app, @PlainDropDownValueChanged, true);
            app.PlainDropDown.Value = 'X';
            app.ReapeatSpinnerLabel = uilabel(app.FiguresPanel);
            app.ReapeatSpinnerLabel.Text = 'Reapeat';
            app.ReapeatSpinner = uispinner(app.FiguresPanel);
            app.ReapeatSpinner.ValueChangedFcn = createCallbackFcn(app, @ReapeatSpinnerValueChanged, true);
            app.ZCoorMainAxisLabel = uilabel(app.FiguresPanel);
            app.ZCoorMainAxisLabel.Text = {'Z Coor'; '(Main Axis)'};
            app.ZCoorS2dSpinner = uispinner(app.FiguresPanel);
            app.ZCoorS2dSpinner.ValueChangingFcn = createCallbackFcn(app, @ZCoorS2dSpinnerValueChanging, true);
            app.ZCoorS2dSpinner.ValueChangedFcn = createCallbackFcn(app, @ZCoorS2dSpinnerValueChanged, true);
            app.ZCoorS2dSpinner.Value = 1;
            app.PlainIdxSpinnerLabel = uilabel(app.FiguresPanel);
            app.PlainIdxSpinnerLabel.Text = 'Plain Idx';
            app.PlainIdxSpinner = uispinner(app.FiguresPanel);
            app.PlainIdxSpinner.LowerLimitInclusive = 'off';
            app.PlainIdxSpinner.ValueChangingFcn = createCallbackFcn(app, @PlainIdxSpinnerValueChanging, true);
            app.PlainIdxSpinner.Limits = [0 Inf];
            app.PlainIdxSpinner.ValueChangedFcn = createCallbackFcn(app, @PlainIdxSpinnerValueChanged, true);
            app.PlainIdxSpinner.Value = 1;
            app.mmLabel_14 = uilabel(app.FiguresPanel);
            app.mmLabel_14.Text = '00.00[mm]';
            app.mmLabel_19 = uilabel(app.FiguresPanel);
            app.mmLabel_19.Text = '00.00[mm]';
            app.ConsistencyPlotTab = uitabgroup(app.UIFigure);
            app.ConsistencyPlotTab.Visible = 'off';
            app.AllFramesStatisticsTab = uitab(app.ConsistencyPlotTab);
            app.AllFramesStatisticsTab.Title = 'All Frames Statistics';
            app.axAllFrames = uiaxes(app.AllFramesStatisticsTab);
            title(app.axAllFrames, 'Time Frame Length vs. Intensity')
            xlabel(app.axAllFrames, 'Time Frame Length [s]')
            ylabel(app.axAllFrames, 'Intensity')
            app.CurrentFrameTab = uitab(app.ConsistencyPlotTab);
            app.CurrentFrameTab.Title = 'Current Frame';
            app.axCurFrame = uiaxes(app.CurrentFrameTab);
            title(app.axCurFrame, 'Current Frame''s Set')
            xlabel(app.axCurFrame, 'X')
            ylabel(app.axCurFrame, 'Y')
            app.CurrentSetQuantsTab = uitab(app.ConsistencyPlotTab);
            app.CurrentSetQuantsTab.Title = 'Current Set (Quants)';
            app.axCurSet = uiaxes(app.CurrentSetQuantsTab);
            title(app.axCurSet, 'Current Set Qunata')
            xlabel(app.axCurSet, 'X')
            ylabel(app.axCurSet, 'Y')
            app.FramesNavigatorTab = uitab(app.ConsistencyPlotTab);
            app.FramesNavigatorTab.Title = 'Frames Navigator';
            app.axNavFrame = uiaxes(app.FramesNavigatorTab);
            title(app.axNavFrame, 'Frame Navigator')
            xlabel(app.axNavFrame, 'Set Number')
            ylabel(app.axNavFrame, 'Fluence Rate')
            app.SetsNavigatorQuantsTab = uitab(app.ConsistencyPlotTab);
            app.SetsNavigatorQuantsTab.Title = 'Sets Navigator (Quants)';
            app.axNavSet = uiaxes(app.SetsNavigatorQuantsTab);
            title(app.axNavSet, 'Set Navigator')
            xlabel(app.axNavSet, 'Set Instance')
            ylabel(app.axNavSet, 'Y')
            app.GlobalParameters = uitabgroup(app.UIFigure);
            app.GlobalParameters.SelectionChangedFcn = createCallbackFcn(app, @GlobalParametersSelectionChanged, true);
            app.SignalExcitationTab = uitab(app.GlobalParameters);
            app.SignalExcitationTab.Title = 'Signal Excitation';
            app.UseQuantCheckBox = uicheckbox(app.SignalExcitationTab);
            app.UseQuantCheckBox.Text = 'Use Quant';
            app.SamplingFrequencyextClkPanel = uipanel(app.SignalExcitationTab);
            app.SamplingFrequencyextClkPanel.Title = 'Sampling Frequency (extClk)';
            app.MHzLabel_2 = uilabel(app.SamplingFrequencyextClkPanel);
            app.MHzLabel_2.Text = '[MHz]';
            app.VLabel_10 = uilabel(app.SamplingFrequencyextClkPanel);
            app.VLabel_10.Text = '[V]';
            app.VLabel_11 = uilabel(app.SamplingFrequencyextClkPanel);
            app.VLabel_11.Text = '[V]';
            app.Label_12 = uilabel(app.SamplingFrequencyextClkPanel);
            app.Label_12.Text = '[%]';
            app.FrequencyEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.FrequencyEditField.Value = 5;
            app.AmplitudeEditField_4Label = uilabel(app.SamplingFrequencyextClkPanel);
            app.AmplitudeEditField_4Label.Text = 'Amplitude';
            app.AmplitudeEditField_4 = uieditfield(app.SamplingFrequencyextClkPanel, 'text');
            app.AmplitudeEditField_4.Value = '1';
            app.OffsetEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'text');
            app.OffsetEditField.Value = '0';
            app.DutyCycleEditFieldLabel = uilabel(app.SamplingFrequencyextClkPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.SamplingFrequencyextClkPanel, 'numeric');
            app.DutyCycleEditField.Value = 50;
            app.UltrasoundSignalPropertiesPanel = uipanel(app.SignalExcitationTab);
            app.UltrasoundSignalPropertiesPanel.Title = 'Ultrasound Signal Properties';
            app.MHzLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.MHzLabel.Text = '[MHz]';
            app.Label_13 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.Label_13.Text = '[#]';
            app.KHzLabel_3 = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.KHzLabel_3.Text = '[KHz]';
            app.SineFrequencyEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SineFrequencyEditFieldLabel.Text = 'Sine Frequency';
            app.SineFrequencyEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SineFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @SineFrequencyEditFieldValueChanged, true);
            app.SineFrequencyEditField.Value = 1.25;
            app.TrainFrequencyEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.TrainFrequencyEditFieldLabel.Text = 'Train Frequency';
            app.TrainFrequencyEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.TrainFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @TrainFrequencyEditFieldValueChanged, true);
            app.TrainFrequencyEditField.Value = 16.5;
            app.SinePeriodsEditFieldLabel = uilabel(app.UltrasoundSignalPropertiesPanel);
            app.SinePeriodsEditFieldLabel.Text = 'Sine Periods';
            app.SinePeriodsEditField = uieditfield(app.UltrasoundSignalPropertiesPanel, 'numeric');
            app.SinePeriodsEditField.ValueChangedFcn = createCallbackFcn(app, @SinePeriodsEditFieldValueChanged, true);
            app.SinePeriodsEditField.Value = 4;
            app.OptimizetrainfrequencyCheckBox = uicheckbox(app.UltrasoundSignalPropertiesPanel);
            app.OptimizetrainfrequencyCheckBox.Text = 'Optimize train frequency';
            app.GeometryPanel = uipanel(app.SignalExcitationTab);
            app.GeometryPanel.Title = 'Geometry';
            app.PhantomdepthEditFieldLabel = uilabel(app.GeometryPanel);
            app.PhantomdepthEditFieldLabel.Text = 'Phantom depth';
            app.PhantomdepthEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.PhantomdepthEditField.Value = 4.6;
            app.TransducerheightEditFieldLabel = uilabel(app.GeometryPanel);
            app.TransducerheightEditFieldLabel.Text = 'Transducer height';
            app.TransducerheightEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.TransducerheightEditField.Value = 11.2;
            app.TransducerdistanceLabel = uilabel(app.GeometryPanel);
            app.TransducerdistanceLabel.Text = 'Transducer distance';
            app.TransducerdistanceEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.TransducerdistanceEditField.Value = 7.3;
            app.msLabel = uilabel(app.GeometryPanel);
            app.msLabel.Text = '[m/s]';
            app.SpeedinMediumEditFieldLabel = uilabel(app.GeometryPanel);
            app.SpeedinMediumEditFieldLabel.Text = 'Speed in Medium';
            app.SpeedinMediumEditField = uieditfield(app.GeometryPanel, 'numeric');
            app.SpeedinMediumEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedinMediumEditFieldValueChanged, true);
            app.SpeedinMediumEditField.Value = 1545;
            app.cmLabel_3 = uilabel(app.GeometryPanel);
            app.cmLabel_3.Text = '[cm]';
            app.cmLabel_2 = uilabel(app.GeometryPanel);
            app.cmLabel_2.Text = '[cm]';
            app.cmLabel = uilabel(app.GeometryPanel);
            app.cmLabel.Text = '[cm]';
            app.GeneralPanel = uipanel(app.SignalExcitationTab);
            app.GeneralPanel.Title = 'General';
            app.TimetosampleEditFieldLabel = uilabel(app.GeneralPanel);
            app.TimetosampleEditFieldLabel.Text = 'Time to sample';
            app.TimetosampleEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.TimetosampleEditField.Value = 2;
            app.UseTimeQuantCheckBox = uicheckbox(app.GeneralPanel);
            app.UseTimeQuantCheckBox.Text = 'Use Time Quant';
            app.UseTimeQuantCheckBox.Value = true;
            app.QuantTimeEditFieldLabel = uilabel(app.GeneralPanel);
            app.QuantTimeEditFieldLabel.Text = 'Quant Time';
            app.QuantTimeEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.QuantTimeEditField.Value = 0.002;
            app.FastAnalysisCheckBox = uicheckbox(app.GeneralPanel);
            app.FastAnalysisCheckBox.Text = 'Fast Analysis';
            app.UseGPUCheckBox = uicheckbox(app.GeneralPanel);
            app.UseGPUCheckBox.Text = 'Use GPU';
            app.CalcOnlyUSFrequencyLabel = uilabel(app.GeneralPanel);
            app.CalcOnlyUSFrequencyLabel.Text = '(Calc Only US Frequency)';
            app.secLabel = uilabel(app.GeneralPanel);
            app.secLabel.Text = '[sec]';
            app.secLabel_2 = uilabel(app.GeneralPanel);
            app.secLabel_2.Text = '[sec]';
            app.UseHadamardCodeCheckBox = uicheckbox(app.GeneralPanel);
            app.UseHadamardCodeCheckBox.Text = 'Use Hadamard Code';
            app.PeripheralsTab = uitab(app.GlobalParameters);
            app.PeripheralsTab.Title = 'Peripherals';
            app.FunctionGeneratorPanel = uipanel(app.PeripheralsTab);
            app.FunctionGeneratorPanel.Title = 'Function Generator';
            app.SclkEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.SclkEditFieldLabel.Text = 'Sclk';
            app.SclkEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.SclkEditField.Value = 100;
            app.DAQFilterDropDownLabel = uilabel(app.FunctionGeneratorPanel);
            app.DAQFilterDropDownLabel.Text = 'DAQ Filter';
            app.DAQFilterDropDown = uidropdown(app.FunctionGeneratorPanel);
            app.DAQFilterDropDown.Items = {'25MHz', '50MHz'};
            app.DAQFilterDropDown.Value = '50MHz';
            app.TriggerWidthEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.TriggerWidthEditFieldLabel.Text = 'Trigger Width';
            app.TriggerWidthEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.TriggerWidthEditField.Value = 500;
            app.AmplitudeEditField_3Label = uilabel(app.FunctionGeneratorPanel);
            app.AmplitudeEditField_3Label.Text = 'Amplitude';
            app.AmplitudeEditField_3 = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.AmplitudeEditField_3.Value = 1;
            app.BiasEditFieldLabel = uilabel(app.FunctionGeneratorPanel);
            app.BiasEditFieldLabel.Text = 'Bias';
            app.BiasEditField = uieditfield(app.FunctionGeneratorPanel, 'numeric');
            app.MSsLabel = uilabel(app.FunctionGeneratorPanel);
            app.MSsLabel.Text = '[MS/s]';
            app.SLabel = uilabel(app.FunctionGeneratorPanel);
            app.SLabel.Text = '[S]';
            app.VLabel_8 = uilabel(app.FunctionGeneratorPanel);
            app.VLabel_8.Text = '[V]';
            app.VLabel_9 = uilabel(app.FunctionGeneratorPanel);
            app.VLabel_9.Text = '[V]';
            app.DigitizerPanel = uipanel(app.PeripheralsTab);
            app.DigitizerPanel.Title = 'Digitizer';
            app.Label_10 = uilabel(app.DigitizerPanel);
            app.Label_10.Text = '[#]';
            app.NumberOfChannelsEditFieldLabel = uilabel(app.DigitizerPanel);
            app.NumberOfChannelsEditFieldLabel.Text = 'Number Of Channels';
            app.NumberOfChannelsEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.NumberOfChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberOfChannelsEditFieldValueChanged, true);
            app.NumberOfChannelsEditField.Value = 4;
            app.Label_11 = uilabel(app.DigitizerPanel);
            app.Label_11.Enable = 'off';
            app.Label_11.Text = '[#]';
            app.PeriodtoSampleEditFieldLabel = uilabel(app.DigitizerPanel);
            app.PeriodtoSampleEditFieldLabel.Text = 'Period to Sample';
            app.PeriodtoSampleEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.PeriodtoSampleEditField.Enable = 'off';
            app.PeriodtoSampleEditField.Value = 8192;
            app.VppLabel = uilabel(app.DigitizerPanel);
            app.VppLabel.Text = '[Vpp]';
            app.VoltsRangeEditFieldLabel = uilabel(app.DigitizerPanel);
            app.VoltsRangeEditFieldLabel.Text = 'Volts Range';
            app.VoltsRangeEditField = uieditfield(app.DigitizerPanel, 'numeric');
            app.VoltsRangeEditField.Value = 2;
            app.SamplingMethodDropDownLabel = uilabel(app.DigitizerPanel);
            app.SamplingMethodDropDownLabel.Text = 'Sampling Method';
            app.SamplingMethodDropDown = uidropdown(app.DigitizerPanel);
            app.SamplingMethodDropDown.Items = {'NPT', 'TS'};
            app.SamplingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SamplingMethodDropDownValueChanged, true);
            app.SamplingMethodDropDown.Value = 'TS';
            app.LaserPanel = uipanel(app.PeripheralsTab);
            app.LaserPanel.Title = 'Laser';
            app.nmLabel = uilabel(app.LaserPanel);
            app.nmLabel.Text = '[nm]';
            app.mWLabel = uilabel(app.LaserPanel);
            app.mWLabel.Text = '[mW]';
            app.KHzLabel_4 = uilabel(app.LaserPanel);
            app.KHzLabel_4.Text = '[KHz]';
            app.sLabel_5 = uilabel(app.LaserPanel);
            app.sLabel_5.Text = '[s]';
            app.WavelengthEditFieldLabel = uilabel(app.LaserPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.LaserPanel, 'numeric');
            app.WavelengthEditField.Value = 785;
            app.PowerEditFieldLabel = uilabel(app.LaserPanel);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.LaserPanel, 'numeric');
            app.PowerEditField.Value = 50;
            app.LineWidthEditFieldLabel = uilabel(app.LaserPanel);
            app.LineWidthEditFieldLabel.Text = 'LineWidth';
            app.LineWidthEditField = uieditfield(app.LaserPanel, 'numeric');
            app.LineWidthEditField.Value = 5000;
            app.SpeckleDurationEditFieldLabel = uilabel(app.LaserPanel);
            app.SpeckleDurationEditFieldLabel.Text = 'Speckle Duration';
            app.SpeckleDurationEditField = uieditfield(app.LaserPanel, 'numeric');
            app.SpeckleDurationEditField.Value = 0.5;
            app.TurnOffAFGButton = uibutton(app.PeripheralsTab, 'push');
            app.TurnOffAFGButton.ButtonPushedFcn = createCallbackFcn(app, @TurnOffAFGButtonPushed, true);
            app.TurnOffAFGButton.Text = 'Turn Off AFG';
            app.CloseIOPortButton = uibutton(app.PeripheralsTab, 'push');
            app.CloseIOPortButton.ButtonPushedFcn = createCallbackFcn(app, @CloseIOPortButtonPushed, true);
            app.CloseIOPortButton.Text = 'Close IO Port';
            app.StagesHomeButton = uibutton(app.PeripheralsTab, 'push');
            app.StagesHomeButton.Text = 'Stages Home';
            app.ProblemVarsTab = uitab(app.GlobalParameters);
            app.ProblemVarsTab.Title = 'Problem Vars';
            app.TabGroup2 = uitabgroup(app.ProblemVarsTab);
            app.SystemClkTab = uitab(app.TabGroup2);
            app.SystemClkTab.Title = 'System Clk';
            app.UITable_6 = uitable(app.SystemClkTab);
            app.UITable_6.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_6.RowName = {};
            app.UITable_6.ColumnEditable = false;
            app.USSignalTab = uitab(app.TabGroup2);
            app.USSignalTab.Title = 'US Signal';
            app.UITable_5 = uitable(app.USSignalTab);
            app.UITable_5.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_5.RowName = {};
            app.UITable_5.ColumnEditable = false;
            app.SamplesTab = uitab(app.TabGroup2);
            app.SamplesTab.Title = 'Samples';
            app.UITable = uitable(app.SamplesTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = false;
            app.TimingTab = uitab(app.TabGroup2);
            app.TimingTab.Title = 'Timing';
            app.UITable_3 = uitable(app.TimingTab);
            app.UITable_3.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = false;
            app.DigitizerTab_2 = uitab(app.TabGroup2);
            app.DigitizerTab_2.Title = 'Digitizer';
            app.UITable_4 = uitable(app.DigitizerTab_2);
            app.UITable_4.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_4.RowName = {};
            app.UITable_4.ColumnEditable = false;
            app.LengthTab = uitab(app.TabGroup2);
            app.LengthTab.Title = 'Length';
            app.UITable_2 = uitable(app.LengthTab);
            app.UITable_2.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = false;
            app.FreqTab = uitab(app.TabGroup2);
            app.FreqTab.Title = 'Freq';
            app.UITable_7 = uitable(app.FreqTab);
            app.UITable_7.ColumnName = {'Parameter'; 'Value'; 'Units'};
            app.UITable_7.RowName = {};
            app.UITable_7.ColumnEditable = false;
            app.PlottingTab = uitab(app.GlobalParameters);
            app.PlottingTab.Title = 'Plotting';
            app.AcoustoOpticsPanel = uipanel(app.PlottingTab);
            app.AcoustoOpticsPanel.Title = 'Acousto Optics';
            app.PlotChannelSpinnerLabel = uilabel(app.AcoustoOpticsPanel);
            app.PlotChannelSpinnerLabel.Text = 'Plot Channel';
            app.PlotChannelSpinner = uispinner(app.AcoustoOpticsPanel);
            app.PlotChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @PlotChannelSpinnerValueChanged, true);
            app.PlotChannelSpinner.Value = 1;
            app.ZCoorSpinner_3Label = uilabel(app.AcoustoOpticsPanel);
            app.ZCoorSpinner_3Label.Text = 'Z Coor';
            app.ZCoorAOSpinner = uispinner(app.AcoustoOpticsPanel);
            app.ZCoorAOSpinner.ValueChangedFcn = createCallbackFcn(app, @ZCoorAOSpinnerValueChanged, true);
            app.ZCoorAOSpinner.Value = 1;
            app.QuantSpinnerLabel = uilabel(app.AcoustoOpticsPanel);
            app.QuantSpinnerLabel.Text = 'Quant';
            app.QuantAOSpinner = uispinner(app.AcoustoOpticsPanel);
            app.QuantAOSpinner.ValueChangedFcn = createCallbackFcn(app, @QuantAOSpinnerValueChanged, true);
            app.QuantAOSpinner.Value = 1;
            app.ExternalClkCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.ExternalClkCheckBox.Text = 'External Clk';
            app.UltrasoundSignalCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.UltrasoundSignalCheckBox.Text = 'Ultrasound Signal';
            app.FullSignalCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.FullSignalCheckBox.Text = 'Full Signal';
            app.MeasuredSamplesCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.MeasuredSamplesCheckBox.Text = 'Measured Samples';
            app.NetSamplesCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.NetSamplesCheckBox.Text = 'Net Samples';
            app.ReshapedCheckBox_3 = uicheckbox(app.AcoustoOpticsPanel);
            app.ReshapedCheckBox_3.Text = 'Reshaped';
            app.FFTCheckBox_3 = uicheckbox(app.AcoustoOpticsPanel);
            app.FFTCheckBox_3.Text = 'FFT';
            app.PhiChCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiChCheckBox.Text = 'Phi (Ch)';
            app.PhiCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiCheckBox.Text = 'Phi';
            app.PhiQuantCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.PhiQuantCheckBox.Text = 'Phi (Quant)';
            app.DemultiplexedCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.DemultiplexedCheckBox.Text = 'Demultiplexed';
            app.FreqEnviromentEditFieldLabel = uilabel(app.AcoustoOpticsPanel);
            app.FreqEnviromentEditFieldLabel.Text = 'Freq Enviroment';
            app.FreqEnviromentEditField = uieditfield(app.AcoustoOpticsPanel, 'numeric');
            app.FreqEnviromentEditField.ValueChangedFcn = createCallbackFcn(app, @FreqEnviromentEditFieldValueChanged, true);
            app.FreqEnviromentEditField.Value = 250;
            app.KHzLabel_5 = uilabel(app.AcoustoOpticsPanel);
            app.KHzLabel_5.Text = 'KHz';
            app.mmLabel_16 = uilabel(app.AcoustoOpticsPanel);
            app.mmLabel_16.Text = '00.00[mm]';
            app.dispFullFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.dispFullFFTCheckBox.Text = 'disp Full FFT';
            app.QuantAvgFFTCheckBox = uicheckbox(app.AcoustoOpticsPanel);
            app.QuantAvgFFTCheckBox.Text = 'Quant Avg. FFT';
            app.FiguresControlPanel = uipanel(app.PlottingTab);
            app.FiguresControlPanel.Title = 'Figures Control';
            app.resetLowerAxLimits = uibutton(app.FiguresControlPanel, 'push');
            app.resetLowerAxLimits.ButtonPushedFcn = createCallbackFcn(app, @resetLowerAxLimitsButtonPushed, true);
            app.resetLowerAxLimits.Icon = '61225.png';
            app.resetLowerAxLimits.Text = '';
            app.EndEditFieldLabel = uilabel(app.FiguresControlPanel);
            app.EndEditFieldLabel.Text = 'End';
            app.EndLimitLower = uieditfield(app.FiguresControlPanel, 'numeric');
            app.StartEditFieldLabel = uilabel(app.FiguresControlPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartLimitLower = uieditfield(app.FiguresControlPanel, 'numeric');
            app.LowerAxesLabel = uilabel(app.FiguresControlPanel);
            app.LowerAxesLabel.Text = 'Lower Axes';
            app.resetUpperAxLimits = uibutton(app.FiguresControlPanel, 'push');
            app.resetUpperAxLimits.ButtonPushedFcn = createCallbackFcn(app, @resetUpperAxLimitsButtonPushed, true);
            app.resetUpperAxLimits.Icon = '61225.png';
            app.resetUpperAxLimits.Text = '';
            app.UpperAxesLabel = uilabel(app.FiguresControlPanel);
            app.UpperAxesLabel.Text = 'Upper Axes';
            app.StartEditField_2Label = uilabel(app.FiguresControlPanel);
            app.StartEditField_2Label.Text = 'Start';
            app.StartLimitUpper = uieditfield(app.FiguresControlPanel, 'numeric');
            app.setUpperLimits = uibutton(app.FiguresControlPanel, 'push');
            app.setUpperLimits.ButtonPushedFcn = createCallbackFcn(app, @setUpperLimitsButtonPushed, true);
            app.setUpperLimits.Text = 'set';
            app.setLowerLimits = uibutton(app.FiguresControlPanel, 'push');
            app.setLowerLimits.ButtonPushedFcn = createCallbackFcn(app, @setLowerLimitsButtonPushed, true);
            app.setLowerLimits.Text = 'set';
            app.EndEditField_2Label = uilabel(app.FiguresControlPanel);
            app.EndEditField_2Label.Text = 'End';
            app.EndLimitUpper = uieditfield(app.FiguresControlPanel, 'numeric');
            app.FiguresinindependantwindowCheckBox = uicheckbox(app.FiguresControlPanel);
            app.FiguresinindependantwindowCheckBox.Text = 'Figures in independant window';
            app.StopButton = uibutton(app.PlottingTab, 'push');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop!';
            app.PopoutFiguresPanel = uipanel(app.PlottingTab);
            app.PopoutFiguresPanel.Title = 'Pop out Figures';
            app.PopButton = uibutton(app.PopoutFiguresPanel, 'push');
            app.PopButton.Enable = 'off';
            app.PopButton.Text = 'Pop!';
            app.DropDown = uidropdown(app.PopoutFiguresPanel);
            app.DropDown.Items = {'Acousto Optics', 'Consistency', 'Scan 2D'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Acousto Optics';
            app.acoustoOpticsPlotsTab = uitabgroup(app.UIFigure);
            app.TimeTable = uitab(app.acoustoOpticsPlotsTab);
            app.TimeTable.Title = 'Time Table';
            app.UITable2 = uitable(app.TimeTable);
            app.UITable2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2.RowName = {};
            app.UITable2_2 = uitable(app.TimeTable);
            app.UITable2_2.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_2.RowName = {};
            app.UITable2_3 = uitable(app.TimeTable);
            app.UITable2_3.ColumnName = {'Stage'; 'Time[s]'};
            app.UITable2_3.RowName = {};
            app.FFTTab = uitab(app.acoustoOpticsPlotsTab);
            app.FFTTab.Title = 'FFT';
            app.axFFTsm = uiaxes(app.FFTTab);
            title(app.axFFTsm, 'Title')
            xlabel(app.axFFTsm, 'X')
            ylabel(app.axFFTsm, 'Y')
            app.AverageFFTTab = uitab(app.acoustoOpticsPlotsTab);
            app.AverageFFTTab.Title = 'Average FFT';
            app.axQAvgFFT = uiaxes(app.AverageFFTTab);
            title(app.axQAvgFFT, 'Title')
            xlabel(app.axQAvgFFT, 'X')
            ylabel(app.axQAvgFFT, 'Y')
            app.PhiTab = uitab(app.acoustoOpticsPlotsTab);
            app.PhiTab.Title = 'Phi';
            app.axPhi = uiaxes(app.PhiTab);
            title(app.axPhi, 'Title')
            xlabel(app.axPhi, 'X')
            ylabel(app.axPhi, 'Y')
            app.PhichTab = uitab(app.acoustoOpticsPlotsTab);
            app.PhichTab.Title = 'Phi (ch)';
            app.axPhiCh = uiaxes(app.PhichTab);
            title(app.axPhiCh, 'Title')
            xlabel(app.axPhiCh, 'X')
            ylabel(app.axPhiCh, 'Y')
            app.UltrasoundSignalTab = uitab(app.acoustoOpticsPlotsTab);
            app.UltrasoundSignalTab.Title = 'Ultrasound Signal';
            app.axUsSignal = uiaxes(app.UltrasoundSignalTab);
            title(app.axUsSignal, 'Title')
            xlabel(app.axUsSignal, 'X')
            ylabel(app.axUsSignal, 'Y')
            app.ReshapedSignalTab = uitab(app.acoustoOpticsPlotsTab);
            app.ReshapedSignalTab.Title = 'Reshaped Signal';
            app.axReshape = uiaxes(app.ReshapedSignalTab);
            title(app.axReshape, 'Title')
            xlabel(app.axReshape, 'X')
            ylabel(app.axReshape, 'Y')
            app.NetSignalTab = uitab(app.acoustoOpticsPlotsTab);
            app.NetSignalTab.Title = 'Net Signal';
            app.axNetSignal = uiaxes(app.NetSignalTab);
            title(app.axNetSignal, 'Title')
            xlabel(app.axNetSignal, 'X')
            ylabel(app.axNetSignal, 'Y')
            app.DemultiplexedTab = uitab(app.acoustoOpticsPlotsTab);
            app.DemultiplexedTab.Title = 'Demultiplexed';
            app.axDeMul = uiaxes(app.DemultiplexedTab);
            title(app.axDeMul, 'Title')
            xlabel(app.axDeMul, 'X')
            ylabel(app.axDeMul, 'Y')
            app.MeasuredSignalTab = uitab(app.acoustoOpticsPlotsTab);
            app.MeasuredSignalTab.Title = 'Measured Signal';
            app.axMeasSig = uiaxes(app.MeasuredSignalTab);
            title(app.axMeasSig, 'Title')
            xlabel(app.axMeasSig, 'X')
            ylabel(app.axMeasSig, 'Y')
            app.FullSignalTab = uitab(app.acoustoOpticsPlotsTab);
            app.FullSignalTab.Title = 'Full Signal';
            app.axFullSignal = uiaxes(app.FullSignalTab);
            title(app.axFullSignal, 'Title')
            xlabel(app.axFullSignal, 'X')
            ylabel(app.axFullSignal, 'Y')
            app.ExternalClkTab = uitab(app.acoustoOpticsPlotsTab);
            app.ExternalClkTab.Title = 'External Clk';
            app.axExtClk = uiaxes(app.ExternalClkTab);
            title(app.axExtClk, 'Title')
            xlabel(app.axExtClk, 'X')
            ylabel(app.axExtClk, 'Y')
        end
    end

    methods (Access = public)

        % Construct app
        function app = AOGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end